classdef Atten_ComPlot_V3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainApp                 matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        SaveMenu                matlab.ui.container.Menu
        FigureMenu              matlab.ui.container.Menu
        DataOutputMenu          matlab.ui.container.Menu
        AllDataMenu             matlab.ui.container.Menu
        FLOutputMenu            matlab.ui.container.Menu
        LoadMenu                matlab.ui.container.Menu
        ComPlotMenu_2           matlab.ui.container.Menu
        PlotExcelMenu           matlab.ui.container.Menu
        InputDataMenu           matlab.ui.container.Menu
        OPUSMenu                matlab.ui.container.Menu
        ExcelMenu               matlab.ui.container.Menu
        InSbMenu_2              matlab.ui.container.Menu
        MCTMenu_2               matlab.ui.container.Menu
        InGaAsMenu_2            matlab.ui.container.Menu
        ResetMenu               matlab.ui.container.Menu
        AttenMenu               matlab.ui.container.Menu
        ComplotMenu             matlab.ui.container.Menu
        AllMenu                 matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        AttenTab                matlab.ui.container.Tab
        Label                   matlab.ui.control.Label
        aLabel                  matlab.ui.control.Label
        cLabel_2                matlab.ui.control.Label
        bLabel_2                matlab.ui.control.Label
        aLabel_2                matlab.ui.control.Label
        cLabel                  matlab.ui.control.Label
        bLabel                  matlab.ui.control.Label
        CheckBox_6              matlab.ui.control.CheckBox
        CheckBox_5              matlab.ui.control.CheckBox
        CheckBox_4              matlab.ui.control.CheckBox
        CheckBox_3              matlab.ui.control.CheckBox
        CheckBox_2              matlab.ui.control.CheckBox
        CheckBox                matlab.ui.control.CheckBox
        CalculateLossButton     matlab.ui.control.Button
        G2Label                 matlab.ui.control.Label
        G1Label                 matlab.ui.control.Label
        FibreLengthsLabel       matlab.ui.control.Label
        FigureListListBox       matlab.ui.control.ListBox
        FigureListListBoxLabel  matlab.ui.control.Label
        AutoBCLabel             matlab.ui.control.Label
        CheckBox_7              matlab.ui.control.CheckBox
        InSbLamp                matlab.ui.control.Lamp
        InSbLabel               matlab.ui.control.Label
        MCTLamp                 matlab.ui.control.Lamp
        MCTLabel                matlab.ui.control.Label
        InGaAsLamp              matlab.ui.control.Lamp
        InGaAsLabel             matlab.ui.control.Label
        InputDataLabel          matlab.ui.control.Label
        AttenLabel              matlab.ui.control.Label
        a1_EditField            matlab.ui.control.NumericEditField
        b1_EditField            matlab.ui.control.NumericEditField
        c1_EditField            matlab.ui.control.NumericEditField
        a2_EditField            matlab.ui.control.NumericEditField
        b2_EditField            matlab.ui.control.NumericEditField
        c2_EditField            matlab.ui.control.NumericEditField
        Figures                 matlab.ui.control.UIAxes
        ComPlotTab              matlab.ui.container.Tab
        ComPlotLabel            matlab.ui.control.Label
        DeleteAllDataButton     matlab.ui.control.Button
        ResetFigureButton       matlab.ui.control.Button
        PlotListListBox         matlab.ui.control.ListBox
        PlotButton              matlab.ui.control.Button
        AxesPropertiesButton    matlab.ui.control.Button
        ComplotAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        InSb=[]; % InSb wavelength after data has been loaded into app
        MCT=[]; % MCT wavelength after data has been loaded into app
        InGaAs=[]; % InGaAs wavelength after data has been loaded into app
        a1; % Intensity after 1a ''
        b1; % Intensity after 1b ''
        c1; % Intensity after 1c ''
        a2; % Intensity after 2a ''
        b2; % Intensity after 2b ''
        c2; % Intensity after 2c ''
        InSbWavelength; %InSb wavelength converted from table 2 array
        MCTWavelength; %MCT wavelength converted from table 2 array
        G1aInSb; %Intensity after 1a cleave section of the fibre for InSb detector converted from table 2 array
        G1bInSb; %Intensity after 1b ''
        G1cInSb; %Intensity after 1c ''
        G2aInSb; %Intensity after 2a ''
        G2bInSb; %Intensity after 2b ''
        G2cInSb; %Intensity after 2c ''
        G1aMCT; %Intensity after 1a cleave section of the fibre for MCT detector converted from table 2 array
        G1bMCT; %Intensity after 1b ''
        G1cMCT; %Intensity after 1c ''
        G2aMCT; %Intensity after 2a ''
        G2bMCT; %Intensity after 2b ''
        G2cMCT; %Intensity after 2c ''
        BestGroup1Cleave; % selected by user on main app window
        BestGroup2Cleave; % ''
        InSb_Best1a2; %
        InSb_Best1b2; %
        InSb_Best1c2; %
        InSb_Best2a1; %
        InSb_Best2b1; %
        InSb_Best2c1; %
        MCT_Best1a2; %
        MCT_Best1b2; %
        MCT_Best1c2; %
        MCT_Best2a1; %
        MCT_Best2b1; %
        MCT_Best2c1; %
        BCG1_I; %
        BCG2_I; %
        BestG1_LM; %
        BestG2_LM; %
        BestG1Lable; %
        BestG2Lable; %
        Lbxnx_m
        MatchedMCT ; %
        CCI_a1a2; %
        CCI_a1b2; %
        CCI_a1c2; %
        CCI_b1a2; %
        CCI_b1b2; %
        CCI_b1c2; %
        CCI_c1a2; %
        CCI_c1b2; %
        CCI_c1c2; %
        CCM_a1a2; %
        CCM_a1b2; %
        CCM_a1c2; %
        CCM_b1a2; %
        CCM_b1b2; %
        CCM_b1c2; %
        CCM_c1a2; %
        CCM_c1b2; %
        CCM_c1c2; %
        CCI_Mean
        CCI_Mean2
        CCM_Mean
        CCI_SD; %
        CCM_SD; %
        HOI_a2b2 ; %
        HOI_a2c2; %
        HOI_b2c2; %
        HOM_a2b2 ; %
        HOM_a2c2; %
        HOM_b2c2; %
        HOI_a2c2_v1; %
        HOM_a2c2_v1; %
        InSbWavelength_v1 %
        HOM %
        FinalPlot%
        FibreDetails%
        SelectedFigure%
        MinAtten%
        MinAtten_Char%
        WvlofMinLoss%
        WvlofMinLoss_Char%
        um_sign%
        AllLegends
        InSbTableRaw % Raw InSb Data Intensities uploaded
        BestCleaveG1Lengths % Best Cleave lengths for group 1
        BestCleaveG2Lengths % '' group 2
        FL_InSb % fibre loss for InSb calculations
        MCTTableRaw % Raw MCT data intensities - what the user uploaded
        Bst_G1_nd_2_MCTIntnstyBsdOnClv %  best group 1 and group 2 MCT intensity based on best cleave
        MCTBLables % MCT best cleave labels
        FL_MCT % fibre loss for MCT calculations
        BestCleaveGroup1_IM % InSb and MCT plots for the best cleave in group 1
        BestCleaveGroup2_IM % '' group 2
        CrossCalculatedInSb % Cross Calculated Raw InSb
        CrossCalculatedMCT % Cross Calculated Raw MCT
        HOI_T % Healed overloaded bands for InSb
        HOM_T % '' for MCT
        LeastNoisyHOIM_2ato2c % Raw least noisy Healed overloaded bands for InSb and MCT assumed to be 2a to 2c
        OriginalnHOM % original loss (unhealed) vs healed overloaded MCT plots (which has been matched to the base of the original loss)
        FinalPlotRaw % the raw data from the final plot
        BestClvG1Lengths
        BestClvG2Lengths
        
        XAXIS % Contains the x axis of the plot being uploaded
        YAXIS % Contains the y axis of the plot being uploaded
        PlotTextBox % The text box
        
        BestCleaveData
        BestGroup2CleaveLabel
        BestInSbG2Lable
        BestInSbG1Lable
        ErrorbySD
        ErrorbySD_Char % Description
        
        InSbRaw
        MCTRaw
        InGaAsRaw
        
        plotNum=[]
        itemsSize
        ComplotFig
        Complotfilename
        
        FigureTitles
        exItemsData
        plotData
        t
    end
    properties (Access = private)
        AxesProperties
        ComPlotTitle='Title';
        ComplotFontSize=16;
        ComplotYLabel='dB/m';
        ComplotXLabel='Wavelength ({\mu}m)';
        ComplotXLim=[1.5 11.5];
        ComplotYLim=[0 inf];
        ComplotLegend=[];
    end
    properties (Access = private)
        LoadOpus
        InSb_transfer=[];
        MCT_transfer=[];
        InGaAs_transfer=[];
    end 
    
    
    methods (Access = public)
        
        function TransferData(app,t1,t2,d)
            if sum(strcmp(["InSb","MCT"],d))==2
                %for InSb and MCT
                app.InSb.Wavelength=t1.InSbWavelength;
                app.InSb.a1=t1.InSb_1a;
                app.InSb.b1=t1.InSb_1b;
                app.InSb.c1=t1.InSb_1c;
                app.InSb.a2=t1.InSb_2a;
                app.InSb.b2=t1.InSb_2b;
                app.InSb.c2=t1.InSb_2c;
                app.InSbLamp.Color='#00FF00';
                app.MCT.Wavelength=t2.MCTWavelength;
                app.MCT.a1=t2.MCT_1a;
                app.MCT.b1=t2.MCT_1b;
                app.MCT.c1=t2.MCT_1c;
                app.MCT.a2=t2.MCT_2a;
                app.MCT.b2=t2.MCT_2b;
                app.MCT.c2=t2.MCT_2c;
                app.MCTLamp.Color='#00FF00';
            end
            
            if sum(strcmp(["InSb","InGaAs"],d))==2
                %for InSb and InGaAs --- CALCULATION METHOD NOT YET AVAILABLE
                app.MCT.Wavelength=t2.MCTWavelength;
                app.MCT.a1=t2.MCT_1a;
                app.MCT.b1=t2.MCT_1b;
                app.MCT.c1=t2.MCT_1c;
                app.MCT.a2=t2.MCT_2a;
                app.MCT.b2=t2.MCT_2b;
                app.MCT.c2=t2.MCT_2c;
                app.MCTLamp.Color='#00FF00';
                app.InGaAs.Wavelength=t2.InGaAsWavelength;
                app.InGaAs.a1=t2.InGaAs_1a;
                app.InGaAs.b1=t2.InGaAs_1b;
                app.InGaAs.c1=t2.InGaAs_1c;
                app.InGaAs.a2=t2.InGaAs_2a;
                app.InGaAs.b2=t2.InGaAs_2b;
                app.InGaAs.c2=t2.InGaAs_2c;
                app.InGaAsLamp.Color='#00FF00';
            end
            
            if sum(strcmp(["MCT","InGaAs"],d))==2
                %for MCT and InGaAs --- CALCULATION METHOD NOT YET AVAILABLE
                app.InSb.Wavelength=t1.InSbWavelength;
                app.InSb.a1=t1.InSb_1a;
                app.InSb.b1=t1.InSb_1b;
                app.InSb.c1=t1.InSb_1c;
                app.InSb.a2=t1.InSb_2a;
                app.InSb.b2=t1.InSb_2b;
                app.InSb.c2=t1.InSb_2c;
                app.InSbLamp.Color='#00FF00';
                app.InGaAs.Wavelength=t2.InGaAsWavelength;
                app.InGaAs.a1=t2.InGaAs_1a;
                app.InGaAs.b1=t2.InGaAs_1b;
                app.InGaAs.c1=t2.InGaAs_1c;
                app.InGaAs.a2=t2.InGaAs_2a;
                app.InGaAs.b2=t2.InGaAs_2b;
                app.InGaAs.c2=t2.InGaAs_2c;
                app.InGaAsLamp.Color='#00FF00';
            end
            app.OPUSMenu.Enable = 'on';
        end
        
        function updateplot(app,ct,cfs,cyl,cxl,cl,cxlm,cylm)
            % Re-enable the Plot Options button
            app.AxesPropertiesButton.Enable = 'on';
            
            if ischar(cxlm)
                xlm=str2double(split(cxlm,','));xlm=xlm';
            else
                xlm=cxlm;
            end
            
            if ischar(cylm)
                ylm=str2double(split(cylm,','));ylm=ylm';
            else
                ylm=cylm;
            end
            
            
            % Store inputs as properties
            app.ComPlotTitle=ct;
            app.ComplotFontSize=cfs;
            app.ComplotYLabel=cyl;
            app.ComplotXLabel=cxl;
            app.ComplotLegend=cl;
            app.ComplotXLim=xlm;
            app.ComplotYLim=ylm;
            
            % Update plot
            app.ComplotAxes.Title.String=ct;
            app.ComplotAxes.Title.FontWeight='bold';
            app.ComplotAxes.Title.FontSize=cfs;
            app.ComplotAxes.Title.FontSmoothing='on';
            app.ComplotAxes.YLabel.String=cyl;
            app.ComplotAxes.YLabel.FontSize=cfs;
            app.ComplotAxes.XLabel.String=cxl;
            app.ComplotAxes.XLabel.FontSize=cfs;
            app.ComplotAxes.XLim=xlm;
            app.ComplotAxes.YLim=ylm;
            
            
            if ~isempty(cl)
                app.AllLegends=legend(app.ComplotAxes,split(string(cl),','));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
            else
            end
            
            
        end
        
        function LoadInSbData(app)
            %==================================================================
            %display image of how the user is supposed to arrange the data if in spreadsheet format
            %1(WL),2(1a),3(1b),4(1c),5(2a),6(2b),7(2c)
            
            %uigetfile for select file
            fileType={'*.*'};
            [filename,filepath]=uigetfile(fileType,'Please Select Your InSb Data');
            if filename==0
                %user clicked the cancel button.
                app.InSbLamp.Color='#A9A9A9';
                return
            else
                FULLPATH=fullfile(filepath,filename);
                app.InSbRaw=readtable(FULLPATH);
                %Allocate imported array to column variable names
                app.InSb.Wavelength=app.InSbRaw(:,1);
                app.InSb.a1=app.InSbRaw(:,2);
                app.InSb.b1=app.InSbRaw(:,3);
                app.InSb.c1=app.InSbRaw(:,4);
                app.InSb.a2=app.InSbRaw(:,5);
                app.InSb.b2=app.InSbRaw(:,6);
                app.InSb.c2=app.InSbRaw(:,7);
                app.InSbLamp.Color='#00FF00';
            end
            %==================================================================
        end
        
        function LoadMCTData(app)
            %==================================================================
            %display image of how the user is supposed to arrange the data if in spreadsheet format
            %1(WL),2(1a),3(1b),4(1c),5(2a),6(2b),7(2c)
            
            %uigetfile for select file
            fileType={'*.*'};
            [filename,filepath]=uigetfile(fileType,'Please Select Your MCT Data');
            if filename==0
                %user clicked the cancel button.
                app.MCTLamp.Color='#A9A9A9';
                return
            else
                FULLPATH=fullfile(filepath,filename);
                disp(FULLPATH)
                app.MCTRaw=readtable(FULLPATH);
                %Allocate imported array to column variable names
                app.MCT.Wavelength=app.MCTRaw(:,1);
                app.MCT.a1=app.MCTRaw(:,2);
                app.MCT.b1=app.MCTRaw(:,3);
                app.MCT.c1=app.MCTRaw(:,4);
                app.MCT.a2=app.MCTRaw(:,5);
                app.MCT.b2=app.MCTRaw(:,6);
                app.MCT.c2=app.MCTRaw(:,7);
                app.MCTLamp.Color='#00FF00';
            end
            %==================================================================
        end
        
        function LoadInGaAsData(app)
            %uigetfile for select file
            fileType={'*.*'};
            [filename,filepath]=uigetfile(fileType,'Please Select Your InGaAs Data');
            if filename==0
                %user clicked the cancel button.
                app.InGaAsLamp.Color='#A9A9A9';
                return
            else
                FULLPATH=fullfile(filepath,filename);
                disp(FULLPATH)
                app.InGaAsRaw=readtable(FULLPATH);
                %Allocate imported array to column variable names
                app.InGaAs.Wavelength=app.InGaAsRaw(:,1);
                app.InGaAs.a1=app.InGaAsRaw(:,2);
                app.InGaAs.b1=app.InGaAsRaw(:,3);
                app.InGaAs.c1=app.InGaAsRaw(:,4);
                app.InGaAs.a2=app.InGaAsRaw(:,5);
                app.InGaAs.b2=app.InGaAsRaw(:,6);
                app.InGaAs.c2=app.InGaAsRaw(:,7);
                app.InGaAsLamp.Color='#00FF00';
            end
            uiconfirm(app.MainApp,'Please note that the method to process this data is still in development.','Invalid Method',"Icon","info","Options",{'OK'},"DefaultOption",1,"CancelOption",1)
            
        end
        
        function legendBDCB(app,~,evnt)
            % This function uses the postion data from where the legend was
            % clicked to toggle a line on/off
            % First, get all of the lines
            lines = app.Figures.Children;
            % Next, create segments based off of the number of lines. Based on
            % where the user clicked in the legend, we will assign that click
            % location to a line. This call generates a grid of equally spaced
            % ranges, with the number of ranges equaling the number of lines
            click_locations = linspace(0,1,numel(lines)+1);
            % Get corresponding bin based on the 2nd value of
            % evnt.IntersectionPoint. This corresponds to the vertical distance
            % within the legend, which we can use to identify what line
            % (roughly) in the legend was clicked
            line_num = discretize(evnt.IntersectionPoint(2),click_locations);
            % Set the visiblility of the corresponding line 'off' or 'on'
            relevant_line = lines(line_num);
            if strcmp(relevant_line.Visible,'on')
                relevant_line.Visible = 'off';
            else
                relevant_line.Visible = 'on';
            end
        end
        
        function remainderData=LoadData(app)
            if app.itemsSize==10
                uiconfirm(app.MainApp,'Cannot load more data. Maximum = 10.','Error',"Icon","error","Options",{'OK'},"CancelOption",1)
                return
            end
            %==================================================================
            %uigetfile for select file
            fileType={'*.*'};
            [app.Complotfilename,pathname]=uigetfile(fileType,'Please Select Your X/Y Data');
            if app.Complotfilename==0
                return
            end
            FULLPATH=fullfile(pathname,app.Complotfilename);
            CombTable=readtable(FULLPATH);
            %Allocate imported array to column variable names
            app.XAXIS=CombTable(:,1);
            app.YAXIS=CombTable(:,2);
            if size(CombTable,2)>2
                remainderData=zeros(size(CombTable));
                for k=1:size(CombTable,2)
                    remainderData(:,k)=table2array(CombTable(:,k));
                end
            else
                remainderData=[];
            end
            app.PlotTextBox=FULLPATH;
            %==================================================================
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %disable label and make invisible
            app.Label.Enable='off';
            app.Label.Visible='off';
            app.InSbLamp.Color='#A9A9A9';
            app.MCTLamp.Color='#A9A9A9';
            app.InGaAsLamp.Color='#A9A9A9';
            
            % Call updateplot to display an initial plot
            updateplot(app,app.ComPlotTitle,app.ComplotFontSize,app.ComplotYLabel,...
                app.ComplotXLabel,app.ComplotLegend,app.ComplotXLim,app.ComplotYLim)
            
        end

        % Button pushed function: CalculateLossButton
        function CalculateLossButtonPushed(app, event)
            tic
            %Include error if no data is loaded into the app
            MCTDPC=isempty(app.MCT);
            InSbDPC=isempty(app.InSb);
            InGaAsDPC=isempty(app.InGaAs);
            
            if sum([MCTDPC,InSbDPC,InGaAsDPC])==3 || sum([MCTDPC,InSbDPC,InGaAsDPC])==2
                uiconfirm(app.MainApp,'Please Insert Data!','No Data!',"Icon","error","Options",{'OK'},"CancelOption",1)
                return
            end
            
            
            %error for empty length fields
            EmptyLengthChecker1=[app.a1_EditField.Value,app.b1_EditField.Value,app.c1_EditField.Value,...
                app.a2_EditField.Value,app.b2_EditField.Value,app.c2_EditField.Value];
            EmptyLengthChecker1Size=size(EmptyLengthChecker1);
            EmptyLengthChecker2=[app.a1_EditField.Value,app.b1_EditField.Value,app.c1_EditField.Value,...
                app.a2_EditField.Value,app.b2_EditField.Value,app.c2_EditField.Value];
            EmptyLengthChecker2(EmptyLengthChecker2==0)=[];
            EmptyLengthChecker2Size=size(EmptyLengthChecker2);
            if EmptyLengthChecker1Size ~= EmptyLengthChecker2Size
                uiconfirm(app.MainApp,'Please check your lengths. One field is empty','Length Fields Incomplete!',"Icon","error","Options",{'OK'},"CancelOption",1)
                return
            end
            
            %Check if user has requested auto best cleave selection, if
            %not, no/multi best cleave selection check will be performed.
            if app.CheckBox_7.Value==0
                %no best cleave error check
                if sum([app.CheckBox.Value,app.CheckBox_2.Value,app.CheckBox_3.Value,...
                        app.CheckBox_4.Value,app.CheckBox_5.Value,app.CheckBox_6.Value])==0
                uiconfirm(app.MainApp,'Please select the best cleave in both groups.','No BC!',"Icon","error","Options",{'OK'},"CancelOption",1)
                return
                end
                
                if sum([app.CheckBox.Value,app.CheckBox_2.Value,app.CheckBox_3.Value])==0
                    uiconfirm(app.MainApp,'Please select the best group 1 cleave.','No BC!',"Icon","error","Options",{'OK'},"CancelOption",1)
                    return
                elseif sum([app.CheckBox.Value,app.CheckBox_2.Value,app.CheckBox_3.Value])>1
                    uiconfirm(app.MainApp,'Multiple best cleaves selected in group 1. Please only select one.','Multiple BCs!',"Icon","error","Options",{'OK'},"CancelOption",1)
                    return
                end
                if sum([app.CheckBox_4.Value,app.CheckBox_5.Value,app.CheckBox_6.Value])==0
                    uiconfirm(app.MainApp,'Please select the best group 2 cleave.','No BC!',"Icon","error","Options",{'OK'},"CancelOption",1)
                    return
                elseif sum([app.CheckBox_4.Value,app.CheckBox_5.Value,app.CheckBox_6.Value])>1
                    uiconfirm(app.MainApp,'Multiple best cleaves selected in group 2. Please only select one.','Multiple BCs!',"Icon","error","Options",{'OK'},"CancelOption",1)
                    return
                end
            else
            end
            
            %%Auto ========================================================
            PleaseWait=uiprogressdlg(app.MainApp,'Title','Calculating Attenuation',"Indeterminate","on");
            
            
            cla(app.Figures)
            %basic code
            %step 1 = calculate the lengths of the sections
            %===============================================================================================
            %concatenate the data
            Lengths.a1=app.a1_EditField.Value;
            Lengths.b1=app.b1_EditField.Value;
            Lengths.c1=app.c1_EditField.Value;
            Lengths.a2=app.a2_EditField.Value;
            Lengths.b2=app.b2_EditField.Value;
            Lengths.c2=app.c2_EditField.Value;
            AllLengths=[Lengths.a1,Lengths.b1,Lengths.c1,Lengths.a2,Lengths.b2,Lengths.c2];
            %===============================================================================================
            
            %Lengths bextween pos1 and pos2
            %===============================================================================================
            a1a2=sum(AllLengths((1),[1,2,3,4]));
            a1b2=sum(AllLengths((1),[1,2,3,4,5]));
            a1c2=sum(AllLengths((1),[1,2,3,4,5,6]));
            b1a2=sum(AllLengths((1),[3,4]));
            b1b2=sum(AllLengths((1),[3,4,5]));
            b1c2=sum(AllLengths((1),[3,4,5,6]));
            c1a2=sum(AllLengths(1,4));
            c1b2=sum(AllLengths((1),[4,5]));
            c1c2=sum(AllLengths((1),[4,5,6]));
            a2b2=sum(AllLengths(1,5));
            a2c2=sum(AllLengths((1),[5,6]));
            b2c2=sum(AllLengths(1,6));
            
            %in 'm'
            conv2m=-1000;
            LBXNX=[a1a2,a1b2,a1c2,b1a2,b1b2,b1c2,c1a2,c1b2,c1c2,a2b2,a2c2,b2c2];
            app.Lbxnx_m=LBXNX/conv2m;
            %===============================================================================================
            %===============================================================================================
            
            %Display the InSb signal intensity before the calculation
            %===============================================================================================
            
            %InSb
            app.G1aInSb=table2array(app.InSb.a1);
            app.G1bInSb=table2array(app.InSb.b1);
            app.G1cInSb=table2array(app.InSb.c1);
            app.G2aInSb=table2array(app.InSb.a2);
            app.G2bInSb=table2array(app.InSb.b2);
            app.G2cInSb=table2array(app.InSb.c2);
            app.InSbWavelength=table2array(app.InSb.Wavelength);
            %//////////////////////////////////////////////////////////////////
            InSbTableRaw_0=[app.InSbWavelength,app.G1aInSb,app.G1bInSb,app.G1cInSb,app.G2aInSb,app.G2bInSb,app.G2cInSb];
            app.InSbTableRaw=array2table(InSbTableRaw_0);
            app.InSbTableRaw.Properties.VariableNames={'Wavelength','a1','b1','c1','a2','b2','c2'};
            %//////////////////////////////////////////////////////////////////
            
            
            %=========
            %Figure:1
            app.Figures.Title.String='Signal Intensity through fibre - InSb: Group 1';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Intensity';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 7];
            app.Figures.YLim=[0 inf];
            hold(app.Figures,'on')
            plot(app.Figures,app.InSbWavelength,app.G1aInSb,'r');
            plot(app.Figures,app.InSbWavelength,app.G1bInSb,'b');
            plot(app.Figures,app.InSbWavelength,app.G1cInSb,'g');
            app.AllLegends=legend(app.Figures,{'1a','1b','1c'});
            hold(app.Figures,'off')
            
            newPlotNum=1;
            app.FigureTitles="Signal Intensity through fibre - InSb: Group 1";
            app.exItemsData={string(["off","off",'_','Signal Intensity through fibre - InSb: Group 1','bold',...
                16,'on','Intensity','Wavelength ({\mu}m)',[1.5 7],[0 inf],{"1a","1b","1c"}])'};
            app.plotData={[app.InSbWavelength,app.G1aInSb,app.G1bInSb,app.G1cInSb]};
            pause(0.75)
           
            %=========
            %Figure:2
            cla(app.Figures,'reset')
            app.Figures.Title.String='Signal Intensity through fibre - InSb: Group 2';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Intensity';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 7];
            app.Figures.YLim=[0 inf];
            hold(app.Figures,'on')
            plot(app.Figures,app.InSbWavelength,app.G2aInSb,'r');
            plot(app.Figures,app.InSbWavelength,app.G2bInSb,'b');
            plot(app.Figures,app.InSbWavelength,app.G2cInSb,'g');
            app.AllLegends=legend(app.Figures,{'2a','2b','2c'});
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Signal Intensity through fibre - InSb: Group 2"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','Signal Intensity through fibre - InSb: Group 2','bold',...
                16,'on','Intensity','Wavelength ({\mu}m)',[1.5 7],[0 inf],{'2a','2b','2c'}])'}];
            app.plotData=[app.plotData,{[app.InSbWavelength,app.G2aInSb,app.G2bInSb,app.G2cInSb]}];
            pause(0.75)
            
            cla(app.Figures)
            
            %=================================================================
            %% Display MCT Signal Intensity Before Calculation
            
            % MCT
            app.G1aMCT=table2array(app.MCT.a1);
            app.G1bMCT=table2array(app.MCT.b1);
            app.G1cMCT=table2array(app.MCT.c1);
            app.G2aMCT=table2array(app.MCT.a2);
            app.G2bMCT=table2array(app.MCT.b2);
            app.G2cMCT=table2array(app.MCT.c2);
            app.MCTWavelength=table2array(app.MCT.Wavelength);
            %//////////////////////////////////////////////////////////////////
            MCTTableRaw_0=[app.MCTWavelength,app.G1aMCT,app.G1bMCT,...
                app.G1cMCT,app.G2aMCT,app.G2bMCT,app.G2cMCT];
            app.MCTTableRaw=array2table(MCTTableRaw_0);
            app.MCTTableRaw.Properties.VariableNames={'Wavelength','a1','b1','c1','a2','b2','c2'};
            %//////////////////////////////////////////////////////////////////
            
            %=========
            %Figure:3
            cla(app.Figures)
            app.Figures.Title.String='Signal intensity through fibre - MCT: Group 1';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Intensity';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 11.5];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.MCTWavelength,app.G1aMCT,'r');
            plot(app.Figures,app.MCTWavelength,app.G1bMCT,'b');
            plot(app.Figures,app.MCTWavelength,app.G1cMCT,'g');
            app.AllLegends=legend(app.Figures,{'1a','1b','1c'});
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Signal intensity through fibre - MCT: Group 1"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','Signal intensity through fibre - MCT: Group 1','bold',...
                16,'on','Intensity','Wavelength ({\mu}m)',[1.5 11.5],[0 inf],{'1a','1b','1c'}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,app.G1aMCT,app.G1bMCT,app.G1cMCT]}];
            pause(0.75)
            
            %=========
            %Figure:4
            cla(app.Figures)
            app.Figures.Title.String='Signal intensity through fibre - MCT: Group 2';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Intensity';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1 11.5];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.MCTWavelength,app.G2aMCT,'r');
            plot(app.Figures,app.MCTWavelength,app.G2bMCT,'b');
            plot(app.Figures,app.MCTWavelength,app.G2cMCT,'g');
            app.AllLegends=legend(app.Figures,{'2a','2b','2c'});
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Signal intensity through fibre - MCT: Group 2"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','Signal intensity through fibre - MCT: Group 2','bold',...
                16,'on','Intensity','Wavelength ({\mu}m)',[1.5 11.5],[0 inf],{'2a','2b','2c'}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,app.G2aMCT,app.G2bMCT,app.G2cMCT]}];
            pause(0.75)
            
            if app.CheckBox_7.Value==0
                %===============================================================================================
                %This section is only activated if the user indicates that
                %they would like to select the best cleave, rather than let
                %the software do it for them.
                %An if statement is needed here. Will input later.
                
                %LBXNX_m=[a1a2,a1b2,a1c2,b1a2,b1b2,b1c2,c1a2,c1b2,c1c2,a2b2,a2c2,b2c2];
                g1avalue_1=app.CheckBox.Value;
                g1bvalue_2=app.CheckBox_2.Value;
                g1cvalue_3=app.CheckBox_3.Value;
                g2avalue_4=app.CheckBox_4.Value;
                g2bvalue_5=app.CheckBox_5.Value;
                g2cvalue_6=app.CheckBox_6.Value;
                
                if g1avalue_1==1
                    app.BestGroup1Cleave=app.G1aInSb;
                    InSb_Best1a2_Length=app.Lbxnx_m(:,1);
                    InSb_Best1b2_Length=app.Lbxnx_m(:,2);
                    InSb_Best1c2_Length=app.Lbxnx_m(:,3);
                    app.BestG1Lable='a1';
                elseif g1bvalue_2==1
                    app.BestGroup1Cleave=app.G1bInSb;
                    InSb_Best1a2_Length=app.Lbxnx_m(:,4);
                    InSb_Best1b2_Length=app.Lbxnx_m(:,5);
                    InSb_Best1c2_Length=app.Lbxnx_m(:,6);
                    app.BestG1Lable='b1';
                elseif g1cvalue_3==1
                    app.BestGroup1Cleave=app.G1cInSb;
                    InSb_Best1a2_Length=app.Lbxnx_m(:,7);
                    InSb_Best1b2_Length=app.Lbxnx_m(:,8);
                    InSb_Best1c2_Length=app.Lbxnx_m(:,9);
                    app.BestG1Lable='c1';
                end
                %//////////////////////////////////////////////////////////////////
                BestCleaveG1Lengths_0=[InSb_Best1a2_Length,InSb_Best1b2_Length,InSb_Best1c2_Length];
                app.BestCleaveG1Lengths=array2table(BestCleaveG1Lengths_0);
                app.BestCleaveG1Lengths.Properties.VariableNames={'L_of_Best1_to_2a','L_of_Best1_to_2b','L_of_Best1_to_2c'};
                app.BestClvG1Lengths=[InSb_Best1a2_Length;InSb_Best1b2_Length;InSb_Best1c2_Length];
                %//////////////////////////////////////////////////////////////////
                if g2avalue_4==1
                    app.BestGroup2Cleave=app.G2aInSb;
                    InSb_Best2a1_Length=app.Lbxnx_m(:,1);
                    InSb_Best2b1_Length=app.Lbxnx_m(:,4);
                    InSb_Best2c1_Length=app.Lbxnx_m(:,7);
                    app.BestG2Lable='a2';
                elseif g2bvalue_5==1
                    app.BestGroup2Cleave=app.G2bInSb;
                    InSb_Best2a1_Length=app.Lbxnx_m(:,2);
                    InSb_Best2b1_Length=app.Lbxnx_m(:,5);
                    InSb_Best2c1_Length=app.Lbxnx_m(:,8);
                    app.BestG2Lable='b2';
                elseif g2cvalue_6==1
                    app.BestGroup2Cleave=app.G2cInSb;
                    InSb_Best2a1_Length=app.Lbxnx_m(:,3);
                    InSb_Best2b1_Length=app.Lbxnx_m(:,6);
                    InSb_Best2c1_Length=app.Lbxnx_m(:,9);
                    app.BestG2Lable='c2';
                end
                %//////////////////////////////////////////////////////////////////
                BestCleaveG2Lengths_0=[InSb_Best2a1_Length,InSb_Best2b1_Length,InSb_Best1c2_Length];
                app.BestCleaveG2Lengths=array2table(BestCleaveG2Lengths_0);
                app.BestCleaveG2Lengths.Properties.VariableNames={'L_of_Best2_to_1a','L_of_Best2_to_1b','L_of_Best2_to_1c'};
                app.BestClvG2Lengths=[InSb_Best2a1_Length;InSb_Best2b1_Length;InSb_Best1c2_Length];
                %//////////////////////////////////////////////////////////////////
                
                %% Calculate loss of g1 based on best cleave from g2 ((InSb))
                %            %use the new lengths calculated for the equation
                %LBXNX_m=[a1a2,a1b2,a1c2,b1a2,b1b2,b1c2,c1a2,c1b2,c1c2,a2b2,a2c2,b2c2];
                app.InSb_Best1a2=(10*log10(app.BestGroup1Cleave./app.G2aInSb)/InSb_Best1a2_Length);   %remember you have nullified the -ve L when cross calculating in the opposite direction
                app.InSb_Best1b2=(10*log10(app.BestGroup1Cleave./app.G2bInSb)/InSb_Best1b2_Length);
                app.InSb_Best1c2=(10*log10(app.BestGroup1Cleave./app.G2cInSb)/InSb_Best1c2_Length);
                
                app.InSb_Best2a1=(10*log10(app.BestGroup2Cleave./app.G1aInSb)/InSb_Best2a1_Length);
                app.InSb_Best2b1=(10*log10(app.BestGroup2Cleave./app.G1bInSb)/InSb_Best2b1_Length);
                app.InSb_Best2c1=(10*log10(app.BestGroup2Cleave./app.G1cInSb)/InSb_Best2c1_Length);
                
                
                %//////////////////////////////////////////////////////////////////
                % Arrange results into table
                FL_InSb_0=[app.InSbWavelength,app.InSb_Best1a2,app.InSb_Best1b2,...
                    app.InSb_Best1c2,app.InSb_Best2a1,app.InSb_Best2b1,app.InSb_Best2c1];
                app.FL_InSb=array2table(FL_InSb_0);
                app.FL_InSb.Properties.VariableNames={'Wavelength','Best1_2a','Best1_2b','Best1_2c','Best2_1a','Best2_1b','Best2_1c'};
                %//////////////////////////////////////////////////////////////////
                
            elseif app.CheckBox_7.Value==1
                %////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////
                %code for calculating the best cleave automatically. In this
                %section, if the user indicates they would like the best
                %cleave to be automatically selected by the software, this
                %section of the code will execute.
                %Note: the best cleave in group 1, will result in the lowest
                %loss, whereas the best cleave in group 2, will result in the
                %highest loss because you are back calculating. So what you must do is 1a-2a/2b/2c 1b-2a/2b/2c
                %and 1c-2a/2b/2c then do the same in reverse: 2a-1a/1b/1c
                %2b-1a/1b/1c and 2c-1a/1b/1c
                %LBXNX_m=[a1a2,a1b2,a1c2,b1a2,b1b2,b1c2,c1a2,c1b2,c1c2,a2b2,a2c2,b2c2];
                
                %Determining best group 2 cleave assuming 1a to be the best (highest loss is best)
                app.BestCleaveData.G2.InSba1a2=(10*log10(app.G1aInSb./app.G2aInSb)/app.Lbxnx_m(:,1));
                app.BestCleaveData.G2.InSba1b2=(10*log10(app.G1aInSb./app.G2bInSb)/app.Lbxnx_m(:,2));
                app.BestCleaveData.G2.InSba1c2=(10*log10(app.G1aInSb./app.G2cInSb)/app.Lbxnx_m(:,3));
                
                %when first writing the code, the variables below were used.
                %So instead of re-writing each and every variable, it is
                %easier to do this.
                app.InSb_Best1a2=app.BestCleaveData.G2.InSba1a2;
                app.InSb_Best1b2=app.BestCleaveData.G2.InSba1b2;
                app.InSb_Best1c2=app.BestCleaveData.G2.InSba1c2;
                
                %Determine the highest point for each plot between 3 and 4 um.
                %First find the location of 3um to 4um.
                %Cut that section out of each plot and find the minimum for
                %each array.
                %compare each array and find the minimum. This will be the
                %best cleave.
                %use if statement to assign the full array for this cleave to
                %a variable, which will be used to determine the best cleave
                %for group one in the following calculations.
                [InSbbstg2Rw_3um,~]=find(abs(app.InSbWavelength-3)<0.01);
                [InSbbstg2Rw_4um,~]=find(abs(app.InSbWavelength-4)<0.01);
                app.BestCleaveData.G2.InSbMaxa1a2=abs(max(app.BestCleaveData.G2.InSba1a2(InSbbstg2Rw_3um:InSbbstg2Rw_4um,:)));
                app.BestCleaveData.G2.InSbMaxa1b2=abs(max(app.BestCleaveData.G2.InSba1b2(InSbbstg2Rw_3um:InSbbstg2Rw_4um,:)));
                app.BestCleaveData.G2.InSbMaxa1c2=abs(max(app.BestCleaveData.G2.InSba1c2(InSbbstg2Rw_3um:InSbbstg2Rw_4um,:)));
                MaxValsG2InSb=[app.BestCleaveData.G2.InSbMaxa1a2,app.BestCleaveData.G2.InSbMaxa1b2,app.BestCleaveData.G2.InSbMaxa1c2];
                MaxValG2InSb=max([app.BestCleaveData.G2.InSbMaxa1a2,app.BestCleaveData.G2.InSbMaxa1b2,app.BestCleaveData.G2.InSbMaxa1c2]);
                
                if MaxValsG2InSb(1)==MaxValG2InSb
                    app.BestCleaveData.G2.InSbBest2=app.G2aInSb;
                    BestInSbG2CleaveResult=app.BestCleaveData.G2.InSba1a2;
                    app.BestInSbG2Lable='a2';
                    LengthsforBestCleave=app.Lbxnx_m(:,[1 4 7]);
                elseif MaxValsG2InSb(2)==MaxValG2InSb
                    app.BestCleaveData.G2.InSbBest2=app.G2bInSb;
                    BestInSbG2CleaveResult=app.BestCleaveData.G2.InSba1b2;
                    app.BestInSbG2Lable='b2';
                    LengthsforBestCleave=app.Lbxnx_m(:,[2 5 8]);
                elseif MaxValsG2InSb(3)==MaxValG2InSb
                    app.BestCleaveData.G2.InSbBest2=app.G2cInSb;
                    BestInSbG2CleaveResult=app.BestCleaveData.G2.InSba1c2;
                    app.BestInSbG2Lable='c2';
                    LengthsforBestCleave=app.Lbxnx_m(:,[3 6 9]);
                end
                
                %Determining best group 1 cleave (lowest loss is best): take best determined in
                %first calculation and input here.
                app.BestCleaveData.G1.InSbBest2a1=(10*log10(app.BestCleaveData.G2.InSbBest2./app.G1aInSb)/LengthsforBestCleave(1));
                app.BestCleaveData.G1.InSbBest2b1=(10*log10(app.BestCleaveData.G2.InSbBest2./app.G1bInSb)/LengthsforBestCleave(2));
                app.BestCleaveData.G1.InSbBest2c1=(10*log10(app.BestCleaveData.G2.InSbBest2./app.G1cInSb)/LengthsforBestCleave(3));
                
                %when first writing the code, the variables below were used.
                %So instead of re-writing each and every variable, it is
                %easier to do this.
                app.InSb_Best2a1=app.BestCleaveData.G1.InSbBest2a1;
                app.InSb_Best2b1=app.BestCleaveData.G1.InSbBest2b1;
                app.InSb_Best2c1=app.BestCleaveData.G1.InSbBest2c1;
                
                %Determine the lowest point for each plot between 3 and 4 um.
                [InSbbstg1Rw_3um,~]=find(abs(app.InSbWavelength-3)<0.01);
                [InSbbstg1Rw_4um,~]=find(abs(app.InSbWavelength-4)<0.01);
                app.BestCleaveData.G1.InSbMina2a1=abs(min(app.BestCleaveData.G1.InSbBest2a1(InSbbstg1Rw_3um:InSbbstg1Rw_4um,:)));
                app.BestCleaveData.G1.InSbMina2b1=abs(min(app.BestCleaveData.G1.InSbBest2b1(InSbbstg1Rw_3um:InSbbstg1Rw_4um,:)));
                app.BestCleaveData.G1.InSbMina2c1=abs(min(app.BestCleaveData.G1.InSbBest2c1(InSbbstg1Rw_3um:InSbbstg1Rw_4um,:)));
                MinValsG1InSb=[app.BestCleaveData.G1.InSbMina2a1,app.BestCleaveData.G1.InSbMina2b1,app.BestCleaveData.G1.InSbMina2c1];
                MinValG1InSb=min([app.BestCleaveData.G1.InSbMina2a1,app.BestCleaveData.G1.InSbMina2b1,app.BestCleaveData.G1.InSbMina2c1]);
                
                if MinValsG1InSb(1)==MinValG1InSb
                    app.BestCleaveData.G1.InSbBest1=app.G1aInSb;
                    BestInSbG1CleaveResult=app.BestCleaveData.G1.InSbBest2a1;
                    app.BestInSbG1Lable='a1';
                elseif MinValsG1InSb(2)==MinValG1InSb
                    app.BestCleaveData.G1.InSbBest1=app.G1bInSb;
                    BestInSbG1CleaveResult=app.BestCleaveData.G1.InSbBest2b1;
                    app.BestInSbG1Lable='b1';
                elseif MinValsG1InSb(3)==MinValG1InSb
                    app.BestCleaveData.G1.InSbBest1=app.G1cInSb;
                    BestInSbG1CleaveResult=app.BestCleaveData.G1.InSbBest2c1;
                    app.BestInSbG1Lable='c1';
                end
                
                bestg2cleavelabel1=[app.BestInSbG2Lable '_a1'];
                bestg2cleavelabel2=[app.BestInSbG2Lable '_b1'];
                bestg2cleavelabel3=[app.BestInSbG2Lable '_c1'];
                
                % Arrange results into table
                FL_InSb_0=[app.InSbWavelength,app.BestCleaveData.G2.InSba1a2,app.BestCleaveData.G2.InSba1b2,...
                    app.BestCleaveData.G2.InSba1c2,app.BestCleaveData.G1.InSbBest2a1,app.BestCleaveData.G1.InSbBest2b1,...
                    app.BestCleaveData.G1.InSbBest2c1,app.BestCleaveData.G2.InSbBest2,app.BestCleaveData.G1.InSbBest1];
                app.FL_InSb=array2table(FL_InSb_0);
                app.FL_InSb.Properties.VariableNames={'Wavelength','a1_a2','a1_b2','a1_c2',bestg2cleavelabel1,...
                    bestg2cleavelabel2,bestg2cleavelabel3,'Best_Group2_Cleave','Best_Group1_Cleave'};
                
                %////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////
            end
            %Display the loss pre-healing
            
            %===============================================================================================
            %Figure:5
            cla(app.Figures)
            app.Figures.Title.String='Optical Attenuation of Fibre Group 1 - InSb Detector';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 7];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.InSbWavelength,-app.InSb_Best2a1,'r');
            plot(app.Figures,app.InSbWavelength,-app.InSb_Best2b1,'b');
            plot(app.Figures,app.InSbWavelength,-app.InSb_Best2c1,'g');
            
            app.AllLegends=legend(app.Figures,{'1a','1b','1c'});
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Optical Attenuation of Fibre Group 1 - InSb Detector"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','Optical Attenuation of Fibre Group 1 - InSb Detector','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 7],[0 inf],{'1a','1b','1c'}])'}];
            app.plotData=[app.plotData,{[app.InSbWavelength,-app.InSb_Best2a1,-app.InSb_Best2b1,-app.InSb_Best2c1]}];
            pause(0.75)
            
            %===============================================================================================
            %==============
            %Figure:6
            cla(app.Figures)
            app.Figures.Title.String='Optical Attenuation of Fibre Group 2 - InSb Detector';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 7];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.InSbWavelength,app.InSb_Best1a2,'r');
            plot(app.Figures,app.InSbWavelength,app.InSb_Best1b2,'b');
            plot(app.Figures,app.InSbWavelength,app.InSb_Best1c2,'g');
            
            app.AllLegends=legend(app.Figures,{'2a','2b','2c'});
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Optical Attenuation of Fibre Group 2 - InSb Detector"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','Optical Attenuation of Fibre Group 2 - InSb Detector','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 7],[0 inf],{'2a','2b','2c'}])'}];
            app.plotData=[app.plotData,{[app.InSbWavelength,app.InSb_Best1a2,app.InSb_Best1b2,app.InSb_Best1c2]}];
            pause(0.75)
            
            
            % Loss calculation MCT based on best g1 and g2 from InSb
            %===============================================================================================
            %determine which MCT plot is the one to use. Based on the best cleaves chosen
            
            if app.CheckBox_7.Value==0
                if strcmp(app.BestG1Lable,'a1')
                    BestG1MCT= app.G1aMCT;
                    BestMCTG1Lable='a1';
                elseif strcmp(app.BestG1Lable,'b1')
                    BestG1MCT= app.G1bMCT;
                    BestMCTG1Lable='b1';
                elseif strcmp(app.BestG1Lable,'c1')
                    BestG1MCT= app.G1cMCT;
                    BestMCTG1Lable='c1';
                end
                
                if strcmp(app.BestG2Lable, 'a2')
                    BestG2MCT=app.G2aMCT;
                    BestMCTG2Lable='a2';
                elseif strcmp(app.BestG2Lable, 'b2')
                    BestG2MCT=app.G2bMCT;
                    BestMCTG2Lable='b2';
                elseif strcmp(app.BestG2Lable, 'c2')
                    BestG2MCT=app.G2cMCT;
                    BestMCTG2Lable='c2';
                end
                
                app.MCTBLables=[BestMCTG1Lable,BestMCTG2Lable];
                
                Bst_G1_nd_2_MCTIntnstyBsdOnClv_0=[app.MCTWavelength,BestG1MCT,BestG2MCT];
                app.Bst_G1_nd_2_MCTIntnstyBsdOnClv=array2table(Bst_G1_nd_2_MCTIntnstyBsdOnClv_0);
                app.Bst_G1_nd_2_MCTIntnstyBsdOnClv.Properties.VariableNames={'Wavelength','BestG1MCT','BestG2MCT'};
                
                
                app.MCT_Best1a2=(10*log10(BestG1MCT./app.G2aMCT)/InSb_Best1a2_Length);   %remember you have nullified the -ve L when cross calculating in the opposite direction
                app.MCT_Best1b2=(10*log10(BestG1MCT./app.G2bMCT)/InSb_Best1b2_Length);
                app.MCT_Best1c2=(10*log10(BestG1MCT./app.G2cMCT)/InSb_Best1c2_Length);
                
                app.MCT_Best2a1=(10*log10(BestG2MCT./app.G1aMCT)/InSb_Best2a1_Length);
                app.MCT_Best2b1=(10*log10(BestG2MCT./app.G1bMCT)/InSb_Best2b1_Length);
                app.MCT_Best2c1=(10*log10(BestG2MCT./app.G1cMCT)/InSb_Best2c1_Length);
                
                
                %//////////////////////////////////////////////////////////////////
                % Arrange results into table
                
                FL_MCT_0=[app.MCTWavelength,app.MCT_Best1a2,app.MCT_Best1b2,app.MCT_Best1c2,...
                    app.MCT_Best2a1,app.MCT_Best2b1,app.MCT_Best2c1];
                app.FL_MCT=array2table(FL_MCT_0);
                app.FL_MCT.Properties.VariableNames={'Wavelength','MCT_Best1a2','MCT_Best1b2','MCT_Best1c2',...
                    'MCT_Best2a1','MCT_Best2b1','MCT_Best2c1'};
                %//////////////////////////////////////////////////////////////////
            elseif app.CheckBox_7.Value==1
                %////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////
                %code for calculating the best cleave automatically. In this
                %section, if the user indicates they would like the best
                %cleave to be automatically selected by the software, this
                %section of the code will execute.
                %Note: the best cleave in group 2, will result in the lowest
                %loss, whereas the best cleave in group 1, will result in the
                %highest loss because you are back calculating. So what you must do is 1a-2a/2b/2c 1b-2a/2b/2c
                %and 1c-2a/2b/2c then do the same in reverse: 2a-1a/1b/1c
                %2b-1a/1b/1c and 2c-1a/1b/1c
                
                %Determining best group 2 cleave assuming 1a to be the best (lowest loss is best)
                app.BestCleaveData.G2.MCTa1a2=(10*log10(app.G1aMCT./app.G2aMCT)/app.Lbxnx_m(1));
                app.BestCleaveData.G2.MCTa1b2=(10*log10(app.G1aMCT./app.G2bMCT)/app.Lbxnx_m(2));
                app.BestCleaveData.G2.MCTa1c2=(10*log10(app.G1aMCT./app.G2cMCT)/app.Lbxnx_m(3));
                
                %when first writing the code, the variables below were used.
                %So instead of re-writing each and every variable, it is
                %easier to do this.
                app.MCT_Best1a2=app.BestCleaveData.G2.MCTa1a2;
                app.MCT_Best1b2=app.BestCleaveData.G2.MCTa1b2;
                app.MCT_Best1c2=app.BestCleaveData.G2.MCTa1c2;
                
                %Determine the lowest point for each plot between 5 and 7 um.
                %First find the location of 3um to 4um.
                %Cut that section out of each plot and find the minimum for
                %each array.
                %compare each array and find the minimum. This will be the
                %best cleave.
                %use if statement to assign the full array for this cleave to
                %a variable, which will be used to determine the best cleave
                %for group one in the following calculations.
                [MCTbstg2Rw_3um,~]=find(abs(app.MCTWavelength-5)<0.01);
                [MCTbstg2Rw_4um,~]=find(abs(app.MCTWavelength-7)<0.01);
                app.BestCleaveData.G2.MCTMaxa1a2=abs(max(app.BestCleaveData.G2.MCTa1a2(MCTbstg2Rw_3um:MCTbstg2Rw_4um,:)));
                app.BestCleaveData.G2.MCTMaxa1b2=abs(max(app.BestCleaveData.G2.MCTa1b2(MCTbstg2Rw_3um:MCTbstg2Rw_4um,:)));
                app.BestCleaveData.G2.MCTMaxa1c2=abs(max(app.BestCleaveData.G2.MCTa1c2(MCTbstg2Rw_3um:MCTbstg2Rw_4um,:)));
                MaxValsG2MCT=[app.BestCleaveData.G2.MCTMaxa1a2,app.BestCleaveData.G2.MCTMaxa1b2,app.BestCleaveData.G2.MCTMaxa1c2];
                MaxValG2MCT=max([app.BestCleaveData.G2.MCTMaxa1a2,app.BestCleaveData.G2.MCTMaxa1b2,app.BestCleaveData.G2.MCTMaxa1c2]);
                
                if MaxValsG2MCT(1)==MaxValG2MCT
                    app.BestCleaveData.G2.MCTBest2=app.G2aMCT;
                    BestMCTG2CleaveResult=app.BestCleaveData.G2.MCTa1a2;
                    app.BestG2Lable='a2';
                    LengthsforBestCleave=app.Lbxnx_m(:,[1 4 7]);
                elseif MaxValsG2MCT(2)==MaxValG2MCT
                    app.BestCleaveData.G2.MCTBest2=app.G2bMCT;
                    BestMCTG2CleaveResult=app.BestCleaveData.G2.MCTa1b2;
                    app.BestG2Lable='b2';
                    LengthsforBestCleave=app.Lbxnx_m(:,[2 5 8]);
                elseif MaxValsG2MCT(3)==MaxValG2MCT
                    app.BestCleaveData.G2.MCTBest2=app.G2cMCT;
                    BestMCTG2CleaveResult=app.BestCleaveData.G2.MCTa1c2;
                    app.BestG2Lable='c2';
                    LengthsforBestCleave=app.Lbxnx_m(:,[3 6 9]);
                end
                
                
                %Determining best group 1 cleave (highest loss is best): take best determined in
                %first calculation and input here.
                app.BestCleaveData.G1.MCTBest2a1=(10*log10(app.BestCleaveData.G2.MCTBest2./app.G1aMCT)/LengthsforBestCleave(1));
                app.BestCleaveData.G1.MCTBest2b1=(10*log10(app.BestCleaveData.G2.MCTBest2./app.G1bMCT)/LengthsforBestCleave(2));
                app.BestCleaveData.G1.MCTBest2c1=(10*log10(app.BestCleaveData.G2.MCTBest2./app.G1cMCT)/LengthsforBestCleave(3));
                
                %when first writing the code, the variables below were used.
                %So instead of re-writing each and every variable, it is
                %easier to do this.
                app.MCT_Best2a1=app.BestCleaveData.G1.MCTBest2a1;
                app.MCT_Best2b1=app.BestCleaveData.G1.MCTBest2b1;
                app.MCT_Best2c1=app.BestCleaveData.G1.MCTBest2c1;
                
                %Determine the highest point for each plot between 5.0 and 7.5 um.
                [MCTbstg1Rw_3um,~]=find(abs(app.MCTWavelength-5.0)<0.01);
                [MCTbstg1Rw_4um,~]=find(abs(app.MCTWavelength-7.5)<0.01);
                app.BestCleaveData.G1.MCTMina2a1=abs(min(app.BestCleaveData.G1.MCTBest2a1(MCTbstg1Rw_3um:MCTbstg1Rw_4um,:)));
                app.BestCleaveData.G1.MCTMina2b1=abs(min(app.BestCleaveData.G1.MCTBest2b1(MCTbstg1Rw_3um:MCTbstg1Rw_4um,:)));
                app.BestCleaveData.G1.MCTMina2c1=abs(min(app.BestCleaveData.G1.MCTBest2c1(MCTbstg1Rw_3um:MCTbstg1Rw_4um,:)));
                MinValsG1MCT=[app.BestCleaveData.G1.MCTMina2a1,app.BestCleaveData.G1.MCTMina2b1,app.BestCleaveData.G1.MCTMina2c1];
                MinValG1MCT=min([app.BestCleaveData.G1.MCTMina2a1,app.BestCleaveData.G1.MCTMina2b1,app.BestCleaveData.G1.MCTMina2c1]);
                
                if MinValsG1MCT(1)==MinValG1MCT
                    app.BestCleaveData.G1.MCTBest1=app.G1aMCT;
                    BestMCTG1CleaveResult=app.BestCleaveData.G1.MCTBest2a1;
                    app.BestG1Lable='a1';
                elseif MinValsG1MCT(2)==MinValG1MCT
                    app.BestCleaveData.G1.MCTBest1=app.G1bMCT;
                    BestMCTG1CleaveResult=app.BestCleaveData.G1.MCTBest2b1;
                    app.BestG1Lable='b1';
                elseif MinValsG1MCT(3)==MinValG1MCT
                    app.BestCleaveData.G1.MCTBest1=app.G1cMCT;
                    BestMCTG1CleaveResult=app.BestCleaveData.G1.MCTBest2c1;
                    app.BestG1Lable='c1';
                end
                
                bestg2cleavelabel1=[app.BestG2Lable '_a1'];
                bestg2cleavelabel2=[app.BestG2Lable '_b1'];
                bestg2cleavelabel3=[app.BestG2Lable '_c1'];
                
                % Arrange results into table
                FL_MCT_0=[app.MCTWavelength,app.BestCleaveData.G2.MCTa1a2,app.BestCleaveData.G2.MCTa1b2,...
                    app.BestCleaveData.G2.MCTa1c2,app.BestCleaveData.G1.MCTBest2a1,app.BestCleaveData.G1.MCTBest2b1,...
                    app.BestCleaveData.G1.MCTBest2c1,app.BestCleaveData.G2.MCTBest2,app.BestCleaveData.G1.MCTBest1];
                app.FL_MCT=array2table(FL_MCT_0);
                app.FL_MCT.Properties.VariableNames={'Wavelength','a1_a2','a1_b2','a1_c2',bestg2cleavelabel1,...
                    bestg2cleavelabel2,bestg2cleavelabel3,'Best_Group2_Cleave','Best_Group1_Cleave'};
                
                Bst_G1_nd_2_MCTIntnstyBsdOnClv_0=[app.MCTWavelength,app.BestCleaveData.G1.MCTBest1,app.BestCleaveData.G2.MCTBest2];
                app.Bst_G1_nd_2_MCTIntnstyBsdOnClv=array2table(Bst_G1_nd_2_MCTIntnstyBsdOnClv_0);
                app.Bst_G1_nd_2_MCTIntnstyBsdOnClv.Properties.VariableNames={'Wavelength','BestG1MCT','BestG2MCT'};
                
            end
            %////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////
            
            
            %=========
            %Figure:7
            cla(app.Figures)
            app.Figures.Title.String='Optical Attenuation of Fibre Group 1 - MCT Detector';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 11.5];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.MCTWavelength,-app.MCT_Best2a1,'r');
            plot(app.Figures,app.MCTWavelength,-app.MCT_Best2b1,'b');
            plot(app.Figures,app.MCTWavelength,-app.MCT_Best2c1,'g');
            app.AllLegends=legend(app.Figures,{'1a','1b','1c'});
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Optical Attenuation of Fibre Group 1 - MCT Detector"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','Optical Attenuation of Fibre Group 1 - MCT Detector','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 11.5],[0 inf],{'1a','1b','1c'}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,-app.MCT_Best2a1,-app.MCT_Best2b1,-app.MCT_Best2c1]}];
            pause(0.75)
            
            %=========
            %Figure:8
            cla(app.Figures)
            app.Figures.Title.String='Optical Attenuation of Fibre Group 2 - MCT Detector';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 11.5];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.MCTWavelength,app.MCT_Best1a2,'r');
            plot(app.Figures,app.MCTWavelength,app.MCT_Best1b2,'b');
            plot(app.Figures,app.MCTWavelength,app.MCT_Best1c2,'g');
            legend(app.Figures,{'2a','2b','2c'})
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Optical Attenuation of Fibre Group 2 - MCT Detector"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','Optical Attenuation of Fibre Group 2 - MCT Detector','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 11.5],[0 inf],{'2a','2b','2c'}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,app.MCT_Best1a2,app.MCT_Best1b2,app.MCT_Best1c2]}];
            pause(0.75)
            
            %===============================================================================================
            %Plotting best cleaves
            %% Plotting best cleaves
            % in excel sheet: place data from best cleaves from both detectors on new
            % sheet (e.g. for best group 1 cleave (1a for example), place both MCT and
            % InSb data on same sheet, as with best group 2 cleave.
            % Plot this and find the data point at which they either match or are very
            % similar. If you find this data point on G1 use the same in G2 and vice
            % versa.
            % Following this, determine the difference between the MCT and InSb
            % arrays, and create a new plot with InSb and MCT fused at that point. This
            % new array is created by subracting the difference previously determined
            % from the MCT for the rest of the array. Then plot this.
            NaNcol_1=NaN(233,1); %to insert into insb data so that it can be concatenated with MCT data
            NaNcol_1=array2table(NaNcol_1);
            NaNcol_1.Properties.VariableNames={'bcs1'};
            
            NaNcol_2=NaN(233,1); %to insert into insb data so that it can be concatenated with MCT data
            NaNcol_2=array2table(NaNcol_2);
            NaNcol_2.Properties.VariableNames={'bcs2'};
            % when inputing the difference, you need to replace the NaNs in the vector
            % with the correct value. Find function to do this.
            
            if app.CheckBox_7.Value==0
                if strcmp(app.BestG1Lable,'a1')
                    BestG1_LIn=array2table(app.InSb_Best2a1);
                    app.BestG1_LM=array2table(app.MCT_Best2a1);
                elseif strcmp(app.BestG1Lable,'b1')
                    BestG1_LIn=array2table(app.InSb_Best2b1);
                    app.BestG1_LM=array2table(app.MCT_Best2b1);
                elseif strcmp(app.BestG1Lable,'c1')
                    BestG1_LIn=array2table(app.InSb_Best2c1);
                    app.BestG1_LM=array2table(app.MCT_Best2c1);
                end
                BestG1_LIn.Properties.VariableNames={'bcs1'};
                
                if strcmp(app.BestG2Lable,'a2')
                    BG2LIn=array2table(app.InSb_Best1a2);
                    app.BestG2_LM=array2table(app.MCT_Best1a2);
                elseif strcmp(app.BestG2Lable,'b2')
                    BG2LIn=array2table(app.InSb_Best1b2);
                    app.BestG2_LM=array2table(app.MCT_Best1b2);
                elseif strcmp(app.BestG2Lable,'c2')
                    BG2LIn=array2table(app.InSb_Best1c2);
                    app.BestG2_LM=array2table(app.MCT_Best1c2);
                end
                BG2LIn.Properties.VariableNames={'bcs2'};
                
                %Best Cleave maybe into table
                app.BCG1_I=[BestG1_LIn;NaNcol_1];
                app.BCG1_I.Properties.VariableNames={'bcs1'};
                %BCG1_IM=[BCG1_I,BestG1_LM];
                
                app.BCG2_I=[BG2LIn;NaNcol_2];
                app.BCG2_I.Properties.VariableNames={'bcs2'};
                %BCG2_IM=[BCG2_I,BestG2_LM];
                
            elseif app.CheckBox_7.Value==1
                %BestInSbG1CleaveResult
                %BestInSbG2CleaveResult
                %BestMCTG1CleaveResult
                %BestMCTG2CleaveResult
                app.BestG1_LM=array2table(BestMCTG1CleaveResult);
                app.BestG2_LM=array2table(BestMCTG2CleaveResult);
                BestG1_LIn=array2table(BestInSbG1CleaveResult);
                BG2LIn=array2table(BestInSbG2CleaveResult);
                
                BestG1_LIn.Properties.VariableNames={'bcs1'};
                BG2LIn.Properties.VariableNames={'bcs2'};
                
                app.BCG1_I=[BestG1_LIn;NaNcol_1];
                app.BCG1_I.Properties.VariableNames={'bcs1'};
                %BCG1_IM=[BCG1_I,BestG1_LM];
                app.BCG2_I=[BG2LIn;NaNcol_2];
                app.BCG2_I.Properties.VariableNames={'bcs2'};
                %BCG2_IM=[BCG2_I,BestG2_LM];
                
            end
            
            %Plot Best Cleaves
            %=========
            %Figure:9
            cla(app.Figures)
            app.Figures.Title.String='Group 1 - Best Cleave';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 11.5];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.MCTWavelength,-table2array(app.BCG1_I),'r');
            plot(app.Figures,app.MCTWavelength,-table2array(app.BestG1_LM),'b');
            app.AllLegends=legend(app.Figures,{'InSb','MCT'});
            
            Fulllabel_G1=['Best G1 Cleave:' app.BestG1Lable];
            app.Label.Enable='on';
            app.Label.Visible='on';
            app.Label.Text=Fulllabel_G1;
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Group 1 - Best Cleave"];
            app.exItemsData=[app.exItemsData,{string(["on","on",Fulllabel_G1,'Group 1 - Best Cleave','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 11.5],[0 inf],{'InSb','MCT'}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,-table2array(app.BCG1_I),-table2array(app.BestG1_LM)]}];
            pause(0.75)
            
            %=========
            %Figure:10
            cla(app.Figures)
            app.Figures.Title.String='Group 2 - Best Cleave';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1 11.5];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.MCTWavelength,table2array(app.BCG2_I),'r');
            plot(app.Figures,app.MCTWavelength,table2array(app.BestG2_LM),'b');
            app.AllLegends=legend(app.Figures,{'InSb','MCT'});
            
            Fulllabel_G2=['Best G2 Cleave:' app.BestG2Lable];
            app.Label.Enable='on';
            app.Label.Visible='on';
            app.Label.Text=Fulllabel_G2;
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Group 2 - Best Cleave"];
            app.exItemsData=[app.exItemsData,{string(["on","on",Fulllabel_G2,'Group 2 - Best Cleave','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 11.5],[0 inf],{'InSb','MCT'}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,table2array(app.BCG2_I),table2array(app.BestG2_LM)]}];
            pause(0.75)
            
            %//////////////////////////////////////////////////////////////////
            
            BestCleaveGroup1_IM_0=[app.MCTWavelength,-table2array(app.BCG1_I),-table2array(app.BestG1_LM)];
            app.BestCleaveGroup1_IM=array2table(BestCleaveGroup1_IM_0);
            app.BestCleaveGroup1_IM.Properties.VariableNames={'Wavelength','BCG1_I','BCG1_M'};
            
            BestCleaveGroup2_IM_0=[app.MCTWavelength,-table2array(app.BCG2_I),-table2array(app.BestG2_LM)];
            app.BestCleaveGroup2_IM=array2table(BestCleaveGroup2_IM_0);
            app.BestCleaveGroup2_IM.Properties.VariableNames={'Wavelength','BCG2_I','BCG2_M'};
            %//////////////////////////////////////////////////////////////////
            %find a region in the MCT wavelength, find the same place on the InSb plot.
            %Match the MCT to the InSb, then delete the InSb
            %alternatively, you can try to fuse the insb to the mct.
            
            %wavelength chosen for matching is 4.80 um.
            %i need to find a way to make this more dynamic so that if the resolution is changed, this won't ruin the app.
            %maybe include a system that asks the user to type in a wavelength to 3decimal places, and this will be found on the matrix
            %the position will be placed in a variable, and then the differences at that wavelength used to match the plots
            
            %Calculate the differences in group 1 only needed (group 2 is for cross calculation)
            InSbandMCT=[-table2array(app.BCG1_I),-table2array(app.BestG1_LM)];
            Diff_b_I_M=diff(InSbandMCT,1,2);
            
            if -table2array(app.BCG1_I(2054,1))>-table2array(app.BestG1_LM(2054,1))
                app.MatchedMCT=-table2array(app.BestG1_LM)+Diff_b_I_M(2054,1);
            elseif -table2array(app.BCG1_I(2054,1))<-table2array(app.BestG1_LM(2054,1))
                app.MatchedMCT=-table2array(app.BestG1_LM)-Diff_b_I_M(2054,1);
            end
            %=========
            %Figure:11
            cla(app.Figures)
            app.Figures.Title.String='MCT Plot Matched with Best Group 1 Cleave for InSb vs Unmatched MCT';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 11.5];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.MCTWavelength,-table2array(app.BCG1_I),'r');
            plot(app.Figures,app.MCTWavelength,-table2array(app.BestG1_LM),'b');
            plot(app.Figures,app.MCTWavelength,app.MatchedMCT,'g');
            app.AllLegends=legend(app.Figures,{'InSb','Unmatched MCT','Matched MCT'});
            
            Fulllabel_G1='';
            app.Label.Enable='off';
            app.Label.Visible='off';
            app.Label.Text=Fulllabel_G1;
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"MCT Plot Matched with Best Group 1 Cleave for InSb vs Unmatched MCT"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','MCT Plot Matched with Best Group 1 Cleave for InSb vs Unmatched MCT','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 11.5],[0 inf],{'InSb','Unmatched MCT','Matched MCT'}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,-table2array(app.BCG1_I),-table2array(app.BestG1_LM),app.MatchedMCT]}];
            pause(0.75)
            
            %=========
            %==========
            %Cross Calculation for Error Determination
            %LBXNX_m=[a1a2,a1b2,a1c2,b1a2,b1b2,b1c2,c1a2,c1b2,c1c2,a2b2,a2c2,b2c2];
            %for InSb
            app.CCI_a1a2=(10*log10(app.G1aInSb./app.G2aInSb)/app.Lbxnx_m(:,1));
            app.CCI_a1b2=(10*log10(app.G1aInSb./app.G2bInSb)/app.Lbxnx_m(:,2));
            app.CCI_a1c2=(10*log10(app.G1aInSb./app.G2cInSb)/app.Lbxnx_m(:,3));
            
            app.CCI_b1a2=(10*log10(app.G1bInSb./app.G2aInSb)/app.Lbxnx_m(:,4));
            app.CCI_b1b2=(10*log10(app.G1bInSb./app.G2bInSb)/app.Lbxnx_m(:,5));
            app.CCI_b1c2=(10*log10(app.G1bInSb./app.G2cInSb)/app.Lbxnx_m(:,6));
            
            app.CCI_c1a2=(10*log10(app.G1cInSb./app.G2aInSb)/app.Lbxnx_m(:,7));
            app.CCI_c1b2=(10*log10(app.G1cInSb./app.G2bInSb)/app.Lbxnx_m(:,8));
            app.CCI_c1c2=(10*log10(app.G1cInSb./app.G2cInSb)/app.Lbxnx_m(:,9));
            
            CCI=[app.CCI_a1a2,app.CCI_a1b2,app.CCI_a1c2,app.CCI_b1a2,app.CCI_b1b2,...
                app.CCI_b1c2,app.CCI_c1a2,app.CCI_c1b2,app.CCI_c1c2];
            CCI_Mean_0=mean(CCI,2);
            app.CCI_Mean=[CCI_Mean_0;table2array(NaNcol_1)];
            app.CCI_Mean2=CCI_Mean_0;
            
            %//////////////////////////////////////////////////////////////////
            CCI_00=[app.InSbWavelength,CCI,CCI_Mean_0];
            app.CrossCalculatedInSb=array2table(CCI_00);
            app.CrossCalculatedInSb.Properties.VariableNames={'Wavelength','CCI_a1a2','CCI_a1b2',...
                'CCI_a1c2','CCI_b1a2','CCI_b1b2','CCI_b1c2','CCI_c1a2','CCI_c1b2','CCI_c1c2','Mean_InSb'};
            %//////////////////////////////////////////////////////////////////
            
            %for MCT
            app.CCM_a1a2=(10*log10(app.G1aMCT./app.G2aMCT)/app.Lbxnx_m(:,1));
            app.CCM_a1b2=(10*log10(app.G1aMCT./app.G2bMCT)/app.Lbxnx_m(:,2));
            app.CCM_a1c2=(10*log10(app.G1aMCT./app.G2cMCT)/app.Lbxnx_m(:,3));
            
            app.CCM_b1a2=(10*log10(app.G1bMCT./app.G2aMCT)/app.Lbxnx_m(:,4));
            app.CCM_b1b2=(10*log10(app.G1bMCT./app.G2bMCT)/app.Lbxnx_m(:,5));
            app.CCM_b1c2=(10*log10(app.G1bMCT./app.G2cMCT)/app.Lbxnx_m(:,6));
            
            app.CCM_c1a2=(10*log10(app.G1cMCT./app.G2aMCT)/app.Lbxnx_m(:,7));
            app.CCM_c1b2=(10*log10(app.G1cMCT./app.G2bMCT)/app.Lbxnx_m(:,8));
            app.CCM_c1c2=(10*log10(app.G1cMCT./app.G2cMCT)/app.Lbxnx_m(:,9));
            
            CCM=[app.CCM_a1a2,app.CCM_a1b2,app.CCM_a1c2,app.CCM_b1a2,app.CCM_b1b2,...
                app.CCM_b1c2,app.CCM_c1a2,app.CCM_c1b2,app.CCM_c1c2];
            app.CCM_Mean=mean(CCM,2);
            
            %//////////////////////////////////////////////////////////////////
            CCM_0=[app.MCTWavelength,CCM,app.CCM_Mean];
            app.CrossCalculatedMCT=array2table(CCM_0);
            app.CrossCalculatedMCT.Properties.VariableNames={'Wavelength','CCI_a1a2','CCI_a1b2',...
                'CCI_a1c2','CCI_b1a2','CCI_b1b2','CCI_b1c2','CCI_c1a2','CCI_c1b2','CCI_c1c2','Mean_MCT'};
            %//////////////////////////////////////////////////////////////////
            
            %================
            %Figure 12
            cla(app.Figures)
            app.Figures.Title.String='InSb: Cross Calculated';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1 7];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.InSbWavelength,app.CCI_a1a2,'r');
            plot(app.Figures,app.InSbWavelength,app.CCI_a1b2,'b');
            plot(app.Figures,app.InSbWavelength,app.CCI_a1c2,'g');
            plot(app.Figures,app.InSbWavelength,app.CCI_b1a2,'k');
            plot(app.Figures,app.InSbWavelength,app.CCI_b1b2,'m');
            plot(app.Figures,app.InSbWavelength,app.CCI_b1c2,'c');
            plot(app.Figures,app.InSbWavelength,app.CCI_c1a2,'color',[0.4940 0.1840 0.5560]);
            plot(app.Figures,app.InSbWavelength,app.CCI_c1b2,'color',[0.8500 0.3250 0.0980]);
            plot(app.Figures,app.InSbWavelength,app.CCI_c1c2,'color',[0.4660 0.6740 0.1880]);
            app.AllLegends=legend(app.Figures,{'1a-2a','1a-2b','1a-2c','1b-2a','1b-2b','1b-2c','1c-2a','1c-2b','1c-2c'});
            
            Fulllabel_G1='';
            app.Label.Enable='off';
            app.Label.Visible='off';
            app.Label.Text=Fulllabel_G1;
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"InSb: Cross Calculated"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','InSb: Cross Calculated','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 7],[0 inf],{'1a-2a','1a-2b','1a-2c','1b-2a','1b-2b','1b-2c','1c-2a','1c-2b','1c-2c'}])'}];
            app.plotData=[app.plotData,{[app.InSbWavelength,app.CCI_a1a2,app.CCI_a1b2,app.CCI_a1c2,app.CCI_b1a2,app.CCI_b1b2,...
                app.CCI_b1c2,app.CCI_c1a2,app.CCI_c1b2,app.CCI_c1c2]}];
            pause(0.75)
            
            %================
            %Figure 13
            cla(app.Figures)
            app.Figures.Title.String='MCT: Cross Calculated';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1 11.5];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.MCTWavelength,app.CCM_a1a2,'r');
            plot(app.Figures,app.MCTWavelength,app.CCM_a1b2,'b');
            plot(app.Figures,app.MCTWavelength,app.CCM_a1c2,'g');
            plot(app.Figures,app.MCTWavelength,app.CCM_b1a2,'k');
            plot(app.Figures,app.MCTWavelength,app.CCM_b1b2,'m');
            plot(app.Figures,app.MCTWavelength,app.CCM_b1c2,'c');
            plot(app.Figures,app.MCTWavelength,app.CCM_c1a2,'color',[0.4940 0.1840 0.5560]);
            plot(app.Figures,app.MCTWavelength,app.CCM_c1b2,'color',[0.8500 0.3250 0.0980]);
            plot(app.Figures,app.MCTWavelength,app.CCM_c1c2,'color',[0.4660 0.6740 0.1880]);
            app.AllLegends=legend(app.Figures,{'1a-2a','1a-2b','1a-2c','1b-2a','1b-2b','1b-2c','1c-2a','1c-2b','1c-2c'});
            
            Fulllabel_G1='';
            app.Label.Enable='off';
            app.Label.Visible='off';
            app.Label.Text=Fulllabel_G1;
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"MCT: Cross Calculated"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','MCT: Cross Calculated','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 11.5],[0 inf],{'1a-2a','1a-2b','1a-2c','1b-2a','1b-2b','1b-2c','1c-2a','1c-2b','1c-2c'}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,app.CCM_a1a2,app.CCM_a1b2,app.CCM_a1c2,app.CCM_b1a2,app.CCM_b1b2,...
                app.CCM_b1c2,app.CCM_c1a2,app.CCM_c1b2,app.CCM_c1c2]}];
            pause(0.75)
            
            %==========================
            %Standard deviation of the cross calculated plots
            CCI_SD1=std(CCI,0,2);
            NaNcol_1=table2array(NaNcol_1);
            app.CCI_SD=[CCI_SD1;NaNcol_1];
            app.CCM_SD=std(CCM,0,2);
            
            %=========
            %Figure 14
            cla(app.Figures)
            app.Figures.Title.String='Error of InSb and MCT Attenuation by Standard Deviation';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 11.5];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.MCTWavelength,app.CCI_SD,'r');
            plot(app.Figures,app.MCTWavelength,app.CCM_SD,'b');
            app.AllLegends=legend(app.Figures,{'InSb','MCT'});
            
            Fulllabel_G1='';
            app.Label.Enable='off';
            app.Label.Visible='off';
            app.Label.Text=Fulllabel_G1;
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Error of InSb and MCT Attenuation by Standard Deviation"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','Error of InSb and MCT Attenuation by Standard Deviation','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 11.5],[0 inf],{'InSb','MCT'}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,app.CCI_SD,app.CCM_SD]}];
            pause(0.75)
            
            %===============================================================================================
            %Healing Plots (Group 2)
            %LBXNX_m=[a1a2,a1b2,a1c2,b1a2,b1b2,b1c2,c1a2,c1b2,c1c2,a2b2,a2c2,b2c2];
            %InSb
            app.HOI_a2b2=(10*log10(app.G2aInSb./app.G2bInSb)/app.Lbxnx_m(:,10));
            app.HOI_a2c2=(10*log10(app.G2aInSb./app.G2cInSb)/app.Lbxnx_m(:,11));
            app.HOI_b2c2=(10*log10(app.G2bInSb./app.G2cInSb)/app.Lbxnx_m(:,12));
            
            %MCT
            app.HOM_a2b2=(10*log10(app.G2aMCT./app.G2bMCT)/app.Lbxnx_m(:,10));
            app.HOM_a2c2=(10*log10(app.G2aMCT./app.G2cMCT)/app.Lbxnx_m(:,11));
            app.HOM_b2c2=(10*log10(app.G2bMCT./app.G2cMCT)/app.Lbxnx_m(:,12));
            
            %Tabulate
            %//////////////////////////////////////////////////////////////////
            HOI_T_0=[app.HOI_a2b2,app.HOI_a2c2,app.HOI_b2c2];
            HOI_T_00=[app.InSbWavelength,HOI_T_0];
            app.HOI_T=array2table(HOI_T_00);
            app.HOI_T.Properties.VariableNames={'Wavelength','HOI_a2b2','HOI_a2c2','HOI_b2c2'};
            %//////////////////////////////////////////////////////////////////
            HOM_T_0=[app.HOM_a2b2,app.HOM_a2c2,app.HOM_b2c2];
            HOM_T_00=[app.MCTWavelength,HOM_T_0];
            app.HOM_T=array2table(HOM_T_00);
            app.HOM_T.Properties.VariableNames={'Wavelength','HOM_a2b2','HOM_a2c2','HOM_b2c2'};
            %//////////////////////////////////////////////////////////////////
            
            %=========
            %Figure 15
            %Plots of healed InSb and MCT
            cla(app.Figures)
            app.Figures.Title.String='Healed InSb Plots';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 7];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.InSbWavelength,app.HOI_a2b2,'r');
            plot(app.Figures,app.InSbWavelength,app.HOI_a2c2,'b');
            plot(app.Figures,app.InSbWavelength,app.HOI_b2c2,'g');
            app.AllLegends=legend(app.Figures,{'2a-2b','2a-2c','2b-2c'});
            
            Fulllabel_G1='';
            app.Label.Enable='off';
            app.Label.Visible='off';
            app.Label.Text=Fulllabel_G1;
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Healed InSb Plots"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','Healed InSb Plots','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 7],[0 inf],{'2a-2b','2a-2c','2b-2c'}])'}];
            app.plotData=[app.plotData,{[app.InSbWavelength,app.HOI_a2b2,app.HOI_a2c2,app.HOI_b2c2]}];
            pause(0.75)
            
            
            %=========
            %Figure 16
            
            cla(app.Figures)
            app.Figures.Title.String='Healed MCT Plots';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 11.5];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.MCTWavelength,app.HOM_a2b2,'r');
            plot(app.Figures,app.MCTWavelength,app.HOM_a2c2,'b');
            plot(app.Figures,app.MCTWavelength,app.HOM_b2c2,'g');
            app.AllLegends=legend(app.Figures,{'2a-2b','2a-2c','2b-2c'});
            
            Fulllabel_G1='';
            app.Label.Enable='off';
            app.Label.Visible='off';
            app.Label.Text=Fulllabel_G1;
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Healed MCT Plots"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','Healed MCT Plots','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 11.5],[0 inf],{'2a-2b','2a-2c','2b-2c'}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,app.HOM_a2b2,app.HOM_a2c2,app.HOM_b2c2]}];
            pause(0.75)
                  
            
            %===============================================================================================
            %Choosing which plot to use by selecting the longest length. So 2a-2c will always be selected.
            %It is assumed this will always have the highest SNR
            %reassign variables.
            app.HOI_a2c2_v1=[app.HOI_a2c2;NaNcol_1];
            app.InSbWavelength_v1=[app.InSbWavelength;NaNcol_1];
            %Plot least noisy plot
            %=========
            %Figure 17
            cla(app.Figures)
            app.Figures.Title.String='Least Noisy Healed InSb and MCT Plots - 2a-2c';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 11.5];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.InSbWavelength_v1,app.HOI_a2c2_v1,'r');
            plot(app.Figures,app.MCTWavelength,app.HOM_a2c2,'b');
            app.AllLegends=legend(app.Figures,{'InSb','MCT'});
            
            Fulllabel_G1='';
            app.Label.Enable='off';
            app.Label.Visible='off';
            app.Label.Text=Fulllabel_G1;
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Least Noisy Healed InSb and MCT Plots - 2a-2c"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','Least Noisy Healed InSb and MCT Plots - 2a-2c','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 11.5],[0 inf],{'InSb','MCT'}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,app.InSbWavelength_v1,app.HOI_a2c2_v1,app.HOM_a2c2]}];
            pause(0.75)
                        
            %Tabulate
            %//////////////////////////////////////////////////////////////////
            LeastNoisyHOIM_2ato2c_0=[app.HOI_a2c2_v1,app.HOM_a2c2];
            LeastNoisyHOIM_2ato2c_00=[app.MCTWavelength,LeastNoisyHOIM_2ato2c_0];
            app.LeastNoisyHOIM_2ato2c=array2table(LeastNoisyHOIM_2ato2c_00);
            app.LeastNoisyHOIM_2ato2c.Properties.VariableNames={'Wavelength','HOI_a2c2','HOM_a2c2'};
            %//////////////////////////////////////////////////////////////////
            
            %===============================================================================================
            %match healed mct (app.MatchedMCT) with unhealed loss (app.HOM_a2c2), at 6 um for example (might ask user for this), then plot
            
            %ask user to choose a wavelength to at which the plots will be
            %matched.
            
            uiconfirm(app.MainApp,['Select wavelength for matching. Use the crosshairs on the new figure window to do this. ' ...
                '***Click a position on the plot then press "Enter" when finished***'],...
                'Matching',"Icon","Info","Options",{'OK'},"DefaultOption",1,"CancelOption",1);
            
            fig10=figure('units','normalized','outerposition',[0 0 1 1]);
            title('Least Noisy Healed InSb and MCT Plots - 2a-2c');
            ylabel('Loss dB/m');
            xlabel('Wavelength ({\mu}m)');
            xlim([1 11.5]);
            ylim([0 inf]);
            
            hold('on')
            plot(app.InSbWavelength_v1,app.HOI_a2c2_v1,'r');
            plot(app.MCTWavelength,app.HOM_a2c2,'b');
            legend('InSb','MCT');
            
            ep=datacursormode;
            ep.Enable='on';
            pause
            hold('off')
            ex=getCursorInfo(ep);
            chosenMatchPoint=ex(1);
            chosenMatchPoint=chosenMatchPoint.Position(1,1);
            
            MatchPrompt={'Please confirm the chosen wavelength:'};
            MatchDlgTitle='Input Wavelength';
            MatchDlgDims=[1 50];
            MatchDefaultInput={num2str(chosenMatchPoint)};
            opts.WindowStyle='normal';
            opts.Resize='on';
            MatchCleavingDlg=inputdlg(MatchPrompt,MatchDlgTitle,MatchDlgDims,MatchDefaultInput,opts);
            
            close(fig10)
            
            [mwrow,mwcol]=find(abs(app.MCTWavelength-str2double(MatchCleavingDlg{1}))<0.001);
            if isempty(mwrow) && isempty(mwcol)
                [mwrow,mwcol]=find(app.MCTWavelength==str2double(MatchCleavingDlg));
            end
            
            intensity_matchedMCT=app.MatchedMCT(mwrow,mwcol);
            intensity_HOM2a2c=app.HOM_a2c2(mwrow,mwcol);
            intensities_at_mw=[intensity_matchedMCT,intensity_HOM2a2c];
            diff_b_Int=diff(intensities_at_mw,1,2);
            diff_b_Int=abs(diff_b_Int);
            if intensity_matchedMCT>intensity_HOM2a2c
                app.HOM=app.HOM_a2c2+diff_b_Int;
            elseif intensity_matchedMCT<intensity_HOM2a2c
                app.HOM=app.HOM_a2c2-diff_b_Int;
            end
            
            %=========
            %Figure 18
            cla(app.Figures)
            app.Figures.Title.String='Attenuation Plot: Healed v Unhealed';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 11.5];
            app.Figures.YLim=[0 inf];
            
            hold(app.Figures,'on')
            plot(app.Figures,app.MCTWavelength,app.MatchedMCT,'r');
            plot(app.Figures,app.MCTWavelength,app.HOM,'b');
            app.AllLegends=legend(app.Figures,{'Unhealed Loss','Healed MCT'});
            hold(app.Figures,'off')
            
            Fulllabel_G1='';
            app.Label.Enable='off';
            app.Label.Visible='off';
            app.Label.Text=Fulllabel_G1;
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;"Attenuation Plot: Healed v Unhealed"];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_','Attenuation Plot: Healed v Unhealed','bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 11.5],[0 inf],{'Unhealed Loss','Healed MCT'}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,app.MatchedMCT,app.HOM]}];
            pause(0.75)
            
            %Tabulate
            %//////////////////////////////////////////////////////////////////
            OriginalnHOM_0=[app.MatchedMCT,app.HOM];
            OriginalnHOM_00=[app.MCTWavelength,OriginalnHOM_0];
            app.OriginalnHOM=array2table(OriginalnHOM_00);
            app.OriginalnHOM.Properties.VariableNames={'Wavelength','Unhealed_Loss','Healed_MCT'};
            %//////////////////////////////////////////////////////////////////
            
            %===============================================================================================
            %PRODUCING THE FINAL PLOT
            %This section shows how the app discerns which sections of the plot from the healed MCT to cleave
            % from and cleave to the unhealed plot.
            %===============================================================================================
            %These cutting points will be selected by the user. For now, I am just setting up the code,
            %then I will add the extra windows later.
            
            
            uiconfirm(app.MainApp,['Select wavelengths for healing. Use the crosshairs on the new figure window to do this. ' ...
                '***Click 8 positions on the plot then press "Enter" when finished.*** ' ...
                '*****See manual for further guidance*****' ],...
                'Matching',"Icon","Info","Options",{'OK'},"DefaultOption",1,"CancelOption",1);
            
            
            fig11=figure('units','normalized','outerposition',[0 0 1 1]);
            title('Healed MCT v Unhealed Loss');
            ylabel('Loss dB/m');
            xlabel('Wavelength ({\mu}m)');
            xlim([1 11.5]);
            ylim([0 inf]);
            
            hold('on')
            plot(app.MCTWavelength,app.MatchedMCT,'r');
            plot(app.MCTWavelength,app.HOM,'b');
            legend('Unhealed Loss','Healed MCT');
            
            eq=datacursormode;
            eq.Enable='on';
            pause
            hold('off')
            et=getCursorInfo(eq);
            Points8=et(1);Points8=Points8.Position(1,1);
            Points7=et(2);Points7=Points7.Position(1,1);
            Points6=et(3);Points6=Points6.Position(1,1);
            Points5=et(4);Points5=Points5.Position(1,1);
            Points4=et(5);Points4=Points4.Position(1,1);
            Points3=et(6);Points3=Points3.Position(1,1);
            Points2=et(7);Points2=Points2.Position(1,1);
            Points1=et(8);Points1=Points1.Position(1,1);
            Points=[Points1;Points2;Points3;Points4;Points5;Points6;Points7;Points8];
            chosenPoints=num2str(Points);
            clear et
            %Ask user what sections they want to clip based on the plot
            %infront of them.
            SecPrompt=["Confirm Section A 1st Cutting Wavelength (*Leave as '0', if you don't want this region replaced*):",...
                "Confirm Section A 2nd Cutting Wavelength (*Leave as '0', if you don't want this region replaced*):",...
                "Confirm Section B 1st Cutting Wavelength (*Leave as '0', if you don't want this region replaced*):",...
                "Confirm Section B 2nd Cutting Wavelength (*Leave as '0', if you don't want this region replaced*):",...
                "Confirm Section C 1st Cutting Wavelength (*Leave as '0', if you don't want this region replaced*):",...
                "Confirm Section C 2nd Cutting Wavelength (*Leave as '0', if you don't want this region replaced*):",...
                "Confirm Section D 1st Cutting Wavelength (*Leave as '0', if you don't want this region replaced*):",...
                "Confirm Section D 2nd Cutting Wavelength (*Leave as '0', if you don't want this region replaced*):"];
            DlgTitle='Confirm Cutting Points For Final Attenuation Plot';
            DlgDims=[1 60];
            
            DefaultInput={chosenPoints(1,:),chosenPoints(2,:),chosenPoints(3,:),...
                chosenPoints(4,:),chosenPoints(5,:),chosenPoints(6,:),...
                chosenPoints(7,:),chosenPoints(8,:)};
            %DefaultInput={'1.000','2.500','4.400','4.800','7.800','8.200','10.700','11.700'};
            opts.WindowStyle='normal';
            opts.Resize='on';
            CleavingDlg=inputdlg(SecPrompt,DlgTitle,DlgDims,DefaultInput,opts);
            
            close(fig11)
            
            app.FinalPlot=app.MatchedMCT;
            if chosenPoints(1)>0 && chosenPoints(2)>0
                sectionA_p1=str2double(CleavingDlg{1});
                sectionA_p2=str2double(CleavingDlg{2});
                [sectionA_p1row,~]=find(abs(app.MCTWavelength-sectionA_p1)<0.001);
                [sectionA_p2row,~]=find(abs(app.MCTWavelength-sectionA_p2)<0.001);
                app.FinalPlot(sectionA_p1row:sectionA_p2row,1)=app.HOM(sectionA_p1row:sectionA_p2row,1);
            end
            if chosenPoints(3)>0 && chosenPoints(4)>0
                sectionB_p1=str2double(CleavingDlg{3});
                sectionB_p2=str2double(CleavingDlg{4});
                [sectionB_p1row,~]=find(abs(app.MCTWavelength-sectionB_p1)<0.001);
                [sectionB_p2row,~]=find(abs(app.MCTWavelength-sectionB_p2)<0.001);
                app.FinalPlot(sectionB_p1row:sectionB_p2row,1)=app.HOM(sectionB_p1row:sectionB_p2row,1);
            end
            if chosenPoints(5)>0 && chosenPoints(6)>0
                sectionC_p1=str2double(CleavingDlg{5});
                sectionC_p2=str2double(CleavingDlg{6});
                [sectionC_p1row,~]=find(abs(app.MCTWavelength-sectionC_p1)<0.001);
                [sectionC_p2row,~]=find(abs(app.MCTWavelength-sectionC_p2)<0.001);
                app.FinalPlot(sectionC_p1row:sectionC_p2row,1)=app.HOM(sectionC_p1row:sectionC_p2row,1);
            end
            if chosenPoints(7)>0 && chosenPoints(8)>0
                sectionD_p1=str2double(CleavingDlg{7});
                sectionD_p2=str2double(CleavingDlg{8});
                [sectionD_p1row,~]=find(abs(app.MCTWavelength-sectionD_p1)<0.001);
                [sectionD_p2row,~]=find(abs(app.MCTWavelength-sectionD_p2)<0.001);
                app.FinalPlot(sectionD_p1row:sectionD_p2row,1)=app.HOM(sectionD_p1row:sectionD_p2row,1);
            end
            

            %plot the final plot
            %=========
            %Figure 20
            %ask for fibre code and composition
            PromptFD={'Fibre Code','Fibre Composition'};
            TitleFD='Provide Fibre Details';
            DimsFD=[1 25];
            DefInFD={'F000XX','Ge_{20}Sb_{10}Se_{70}'};
            app.FibreDetails=inputdlg(PromptFD,TitleFD,DimsFD,DefInFD);
            cla(app.Figures)
            app.Figures.Title.String=['Attenuation of ' app.FibreDetails{2} ' at.% Optical Fibre. Fibre Code = ' app.FibreDetails{1}];
            
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Loss dB/m';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[1.5 10.6];
            app.Figures.YLim=[0 inf];
            hold(app.Figures,'on')
            plot(app.Figures,app.MCTWavelength,app.FinalPlot,'b');
            Fulllabel_G1='';
            app.Label.Enable='off';
            app.Label.Visible='off';
            app.Label.Text=Fulllabel_G1;
            
            [MinSecLowWvl_R,~]=find(abs(app.MCTWavelength-2.001)<0.01);
            [MinSecHighWvl_R,~]=find(abs(app.MCTWavelength-10.001)<0.03);
            MinSecPlot=app.FinalPlot(MinSecLowWvl_R:MinSecHighWvl_R,1);
            app.MinAtten=min(MinSecPlot);
            [PosMinAtten_R,PosMinAtten_C]=find(abs(app.FinalPlot-app.MinAtten)<0.0001);
            app.WvlofMinLoss=app.MCTWavelength(PosMinAtten_R,PosMinAtten_C);
            app.ErrorbySD=app.CCM_SD(PosMinAtten_R,PosMinAtten_C);
            
            [wvlsz1,wvlsz2]=size(app.WvlofMinLoss);
            if wvlsz1>1||wvlsz2>1
                app.WvlofMinLoss=app.WvlofMinLoss(1,1);
                app.ErrorbySD=app.ErrorbySD(1,1);
                app.MinAtten_Char=num2str(app.MinAtten);
                app.WvlofMinLoss_Char=num2str(app.WvlofMinLoss);
                app.ErrorbySD_Char=num2str(app.ErrorbySD);
                scatter(app.Figures,app.WvlofMinLoss,app.MinAtten,'r','diamond');
                scatter(app.Figures,app.WvlofMinLoss,app.MinAtten,'r','diamond'); % second plot is added so that an extra legend entry can be inserted to indicate the error by sd
            else
                app.MinAtten_Char=num2str(app.MinAtten);
                app.WvlofMinLoss_Char=num2str(app.WvlofMinLoss);
                app.ErrorbySD_Char=num2str(app.ErrorbySD);
                scatter(app.Figures,app.WvlofMinLoss,app.MinAtten,'r','diamond');
                scatter(app.Figures,app.WvlofMinLoss,app.MinAtten,'r','diamond'); % second plot is added so that an extra legend entry can be inserted to indicate the error by sd
            end
            app.AllLegends=legend(app.Figures,['Fibre Code = ' app.FibreDetails{1}],['Minimum Loss at ', app.WvlofMinLoss_Char,' {\mu}m = ' app.MinAtten_Char ' dB/m'],['Error by Standard Deviation = ', app.ErrorbySD_Char]);
            app.AllLegends.ButtonDownFcn = @app.legendBDCB;
            hold(app.Figures,'off')
            
            newPlotNum=newPlotNum+1;
            app.FigureTitles=[app.FigureTitles;string(['Attenuation of ' app.FibreDetails{2} ' at.% Optical Fibre. Fibre Code = ' app.FibreDetails{1}])];
            app.exItemsData=[app.exItemsData,{string(["off","off",'_',...
                ['Attenuation of ' app.FibreDetails{2} ' at.% Optical Fibre. Fibre Code = ' app.FibreDetails{1}],'bold',...
                16,'on','Loss dB/m','Wavelength ({\mu}m)',[1.5 10.6],[0 inf],{join(['Fibre Code = ' app.FibreDetails{1}],''),...
                join(['Minimum Loss at ', app.WvlofMinLoss_Char,' {\mu}m = ' app.MinAtten_Char ' dB/m'],''),...
                join(['Error by Standard Deviation = ', app.ErrorbySD_Char],''),app.MinAtten,app.WvlofMinLoss,app.ErrorbySD}])'}];
            app.plotData=[app.plotData,{[app.MCTWavelength,app.FinalPlot]}];
            pause(0.75)
            
            %Tabulate
            %//////////////////////////////////////////////////////////////////
            FinalPlotRaw_0=[app.MCTWavelength,app.FinalPlot];
            app.FinalPlotRaw=array2table(FinalPlotRaw_0);
            app.FinalPlotRaw.Properties.VariableNames={'Wavelength','FinalPlot'};
            %//////////////////////////////////////////////////////////////////
            close(PleaseWait)
            toc
            tic 
            PleaseWait=uiprogressdlg(app.MainApp,'Title','Please Wait','Message','Populating Figure List');
            ItemsData_temp=1:newPlotNum;
            for k=1:newPlotNum
               app.FigureListListBox.Items(k)={char(join(['Figure ', num2str(k), ': ', app.FigureTitles(k)],''))};
               app.FigureListListBox.ItemsData{k}=ItemsData_temp(k);
               pause(1)
               PleaseWait.Value=k/max(newPlotNum);
            end 
            app.FigureListListBox.Enable='on';
            close(PleaseWait)
            toc
        end

        % Value changed function: FigureListListBox
        function FigureListListBoxValueChanged(app, event)
            
            if sum(app.InSbWavelength)==0 || sum(app.MCTWavelength)==0 || isempty(app.InSbWavelength) || isempty(app.MCTWavelength)
                uiconfirm(app.MainApp,'Please load data','No Data',"Icon","error","Options",{'OK'},"CancelOption",1)
                return
            end
            %=====================================================================================================================================================
            if app.FigureListListBox.Value==1
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:16,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                close(PleaseWait)
                app.exItemsData
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==2
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                pause(0.5)
                cla(app.Figures)
                %plot figure 2 and so on keep adding elseifs
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:16,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==3
                %Figure:3
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                pause(0.5)
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                pause(0.5)
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:16,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==4
                %Figure:4
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                pause(0.5)
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                pause(0.5)
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:16,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==5
                %Figure:5
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                pause(0.5)
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:16,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==6
                %Figure:6
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                pause(0.5)
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                pause(0.5)
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:16,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==7
                %Figure:7
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                pause(0.5)
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:16,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==8
                %Figure:8
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                pause(0.5)
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:16,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==9
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                %Figure:9
                cla(app.Figures)
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:15,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                Fulllabel_G1=app.exItemsData{1,app.FigureListListBox.Value}(3,end);
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Label.Text=Fulllabel_G1;
                hold(app.Figures,'off')
                
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==10
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                %Figure:10
                cla(app.Figures)
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:15,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                Fulllabel_G2=app.exItemsData{1,app.FigureListListBox.Value}(3,end);
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Label.Text=Fulllabel_G2;
                hold(app.Figures,'off')
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==11
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                %Figure: 11
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:16,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==12
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                %Figure: 12
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,5),'k');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,6),'m');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,7),'c');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,8),'color',[0.4940 0.1840 0.5560]);
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,9),'color',[0.8500 0.3250 0.0980]);
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,10),'color',[0.4660 0.6740 0.1880]);
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:22,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==13
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                %Figure: 13
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,5),'k');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,6),'m');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,7),'c');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,8),'color',[0.4940 0.1840 0.5560]);
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,9),'color',[0.8500 0.3250 0.0980]);
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,10),'color',[0.4660 0.6740 0.1880]);
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:22,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==14
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                %Figure: 14
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:15,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==15
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                %Figure: 15
                %Plots of healed InSb and MCT
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:16,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==16
                %Figure: 16
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'g');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:16,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==17
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                %Figure: 17
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,2),app.plotData{1,app.FigureListListBox.Value}(:,3),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,4),'b');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:15,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==18
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                %Figure: 18
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'r');
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,3),'b');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:15,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                
                close(PleaseWait)
                %===================================================================================================================================================
            elseif app.FigureListListBox.Value==19
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                cla(app.Figures)
                app.Label.Enable=app.exItemsData{1,app.FigureListListBox.Value}(1,end);
                app.Label.Visible=app.exItemsData{1,app.FigureListListBox.Value}(2,end);
                app.Figures.Title.String=app.exItemsData{1,app.FigureListListBox.Value}(4,end);
                app.Figures.Title.FontWeight=app.exItemsData{1,app.FigureListListBox.Value}(5,end);
                app.Figures.Title.FontSize=str2double(app.exItemsData{1,app.FigureListListBox.Value}(6,end));
                app.Figures.Title.FontSmoothing=app.exItemsData{1,app.FigureListListBox.Value}(7,end);
                app.Figures.YLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(8,end);
                app.Figures.XLabel.String=app.exItemsData{1,app.FigureListListBox.Value}(9,end);
                app.Figures.XLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(10,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(11,end))];
                app.Figures.YLim=[str2double(app.exItemsData{1,app.FigureListListBox.Value}(12,end)) str2double(app.exItemsData{1,app.FigureListListBox.Value}(13,end))];
                hold(app.Figures,'on')
                plot(app.Figures,app.plotData{1,app.FigureListListBox.Value}(:,1),app.plotData{1,app.FigureListListBox.Value}(:,2),'b');
                scatter(app.Figures,app.WvlofMinLoss,app.MinAtten,'r','diamond');
                scatter(app.Figures,app.WvlofMinLoss,app.MinAtten,'r','diamond');
                app.AllLegends=legend(app.Figures,app.exItemsData{1,app.FigureListListBox.Value}(14:16,end));
                app.AllLegends.ButtonDownFcn = @app.legendBDCB;
                hold(app.Figures,'off')
                close(PleaseWait)
                %===================================================================================================================================================
            end
        end

        % Button pushed function: DeleteAllDataButton
        function DeleteAllDataButtonPushed(app, event)
            app.AxesPropertiesButton.Enable = 'on';
            if isempty(app.PlotListListBox.Items)
                return
            end 
            for k=app.itemsSize(2):-1:1
                app.PlotListListBox.ItemsData(k)=[];
                app.PlotListListBox.Items(k)=[];
            end
%             if isempty(app.PlotListListBox.Items)
%                 return
%             elseif app.itemsSize(2)==1
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%             elseif app.itemsSize(2)==2
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%             elseif app.itemsSize(2)==3
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%             elseif app.itemsSize(2)==4
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%             elseif app.itemsSize(2)==5
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%             elseif app.itemsSize(2)==6
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%             elseif app.itemsSize(2)==7
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%             elseif app.itemsSize(2)==8
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%             elseif app.itemsSize(2)==9
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%             elseif app.itemsSize(2)==10
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%                 app.PlotListListBox.Items(1)=[];app.PlotListListBox.ItemsData(1)=[];
%             end
        end

        % Button pushed function: ResetFigureButton
        function ResetFigureButtonPushed(app, event)
            cla(app.ComplotAxes)
            app.AxesPropertiesButton.Enable = 'on';
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            
            if value==1
                app.CheckBox.Value=0;
                app.CheckBox_2.Value=0;
                app.CheckBox_3.Value=0;
                app.CheckBox_4.Value=0;
                app.CheckBox_5.Value=0;
                app.CheckBox_6.Value=0;
                
                app.CheckBox.Enable='off';
                app.CheckBox_2.Enable='off';
                app.CheckBox_3.Enable='off';
                app.CheckBox_4.Enable='off';
                app.CheckBox_5.Enable='off';
                app.CheckBox_6.Enable='off';
                
            else
                app.CheckBox.Value=0;
                app.CheckBox_2.Value=0;
                app.CheckBox_3.Value=0;
                app.CheckBox_4.Value=0;
                app.CheckBox_5.Value=0;
                app.CheckBox_6.Value=0;
                
                app.CheckBox.Enable='on';
                app.CheckBox_2.Enable='on';
                app.CheckBox_3.Enable='on';
                app.CheckBox_4.Enable='on';
                app.CheckBox_5.Enable='on';
                app.CheckBox_6.Enable='on';
                
            end
        end

        % Menu selected function: InSbMenu_2
        function InSbMenu_2Selected(app, event)
            %Include error if no data is loaded into the app
            MCTDPC=isempty(app.MCT);
            InSbDPC=isempty(app.InSb);
            InGaAsDPC=isempty(app.InGaAs);
            if sum([MCTDPC,InSbDPC,InGaAsDPC])==3||sum([MCTDPC,InSbDPC,InGaAsDPC])==2
                app.LoadInSbData;
            elseif sum([MCTDPC,InSbDPC,InGaAsDPC])==1
                selection=uiconfirm(app.MainApp,'Data cannot be loaded. Delete MCT or InGaAs data to continue.','Warning!',...
                    'Icon','warning','Options',{'Return','Delete MCT','Delete InGaAs'},'DefaultOption',1,"CancelOption",1);
                if strcmp(selection,'Return')
                    return
                elseif strcmp(selection,'Delete MCT')
                    app.MCTRaw=[];
                    app.MCT.Wavelength=[];
                    app.MCT.a1=[];
                    app.MCT.b1=[];
                    app.MCT.c1=[];
                    app.MCT.b2=[];
                    app.MCT.c2=[];
                    app.MCTLamp.Color='#A9A9A9';
                elseif strcmp(selection,'Delete InGaAs')
                    app.InGaAsRaw=[];
                    app.InGaAs.Wavelength=[];
                    app.InGaAs.a1=[];
                    app.InGaAs.b1=[];
                    app.InGaAs.c1=[];
                    app.InGaAs.a2=[];
                    app.InGaAs.b2=[];
                    app.InGaAs.c2=[];
                    app.InGaAsLamp.Color='#A9A9A9';
                end
            end
        end

        % Menu selected function: MCTMenu_2
        function MCTMenu_2Selected(app, event)
            MCTDPC=isempty(app.MCT);
            InSbDPC=isempty(app.InSb);
            InGaAsDPC=isempty(app.InGaAs);
            if sum([MCTDPC,InSbDPC,InGaAsDPC])==3||sum([MCTDPC,InSbDPC,InGaAsDPC])==2
                app.LoadMCTData;
            elseif sum([MCTDPC,InSbDPC,InGaAsDPC])==1
                selection=uiconfirm(app.MainApp,'Data cannot be loaded. Delete InSb or InGaAs data to continue.','Warning!',...
                    'Icon','warning','Options',{'Return','Delete InSb','Delete InGaAs'},'DefaultOption',1,"CancelOption",1);
                if strcmp(selection,'Return')
                    return
                elseif strcmp(selection,'Delete InSb')
                    app.InSbRaw=[];
                    app.InSb.Wavelength=[];
                    app.InSb.a1=[];
                    app.InSb.b1=[];
                    app.InSb.c1=[];
                    app.InSb.a2=[];
                    app.InSb.b2=[];
                    app.InSb.c2=[];
                    app.InSbLamp.Color='#A9A9A9';
                elseif strcmp(selection,'Delete InGaAs')
                    app.InGaAsRaw=[];
                    app.InGaAs.Wavelength=[];
                    app.InGaAs.a1=[];
                    app.InGaAs.b1=[];
                    app.InGaAs.c1=[];
                    app.InGaAs.a2=[];
                    app.InGaAs.b2=[];
                    app.InGaAs.c2=[];
                    app.InGaAsLamp.Color='#A9A9A9';
                end
            end
        end

        % Menu selected function: AllDataMenu
        function AllDataMenuSelected(app, event)
            %InSbTableRaw % Raw InSb Data Intensities uploaded
            %BestCleaveG1Lengths % Best Cleave lengths for group 1
            %BestCleaveG2Lengths % '' group 2
            %FL_InSb % fibre loss for InSb calculations
            %MCTTableRaw % Raw MCT data intensities - what the user uploaded
            %Bst_G1_nd_2_MCTIntnstyBsdOnClv %  best group 1 and group 2 MCT intensity based on best cleave
            %MCTBLables % MCT best cleave labels
            %FL_MCT % fibre loss for MCT calculations
            %BestCleaveGroup1_IM % InSb and MCT plots for the best cleave in group 1
            %BestCleaveGroup2_IM % '' group 2
            %CrossCalculatedInSb % Cross Calculated Raw InSb
            %CrossCalculatedMCT % Cross Calculated Raw MCT
            %HOI_T % Healed overloaded bands for InSb
            %HOM_T % '' for MCT
            %LeastNoisyHOIM_2ato2c % Raw least noisy Healed overloaded bands for InSb and MCT assumed to be 2a to 2c
            %OriginalnHOM % original loss (unhealed) vs healed overloaded MCT plots (which has been matched to the base of the original loss)
            %FinalPlotRaw % the raw data from the final plot
            
            if isempty(app.FinalPlotRaw)
                uiconfirm(app.MainApp,'No data available to save.','No data!',...
                    'Icon','error','Options',{'OK'},"CancelOption",1);
                return
            else
            end
            
            fileType={'*.xlsx'};
            [filename,pathname]=uiputfile(fileType,'Save File As');
            if filename==0
                return
            end
            filepath=pathname;
            FULLPATH=fullfile(filepath,filename);
            
            PleaseWait=uiprogressdlg(app.MainApp,'Title','Saving',"Indeterminate","on",'Cancelable',"off");
            writetable(app.InSbTableRaw,FULLPATH,'Sheet',1)
            writetable(app.FL_InSb,FULLPATH,'Sheet',2)
            writetable(app.MCTTableRaw,FULLPATH,'Sheet',3)
            writetable(app.FL_MCT,FULLPATH,'Sheet',4)
            writetable(app.Bst_G1_nd_2_MCTIntnstyBsdOnClv,FULLPATH,'Sheet',4)
            writetable(app.BestCleaveGroup1_IM,FULLPATH,'Sheet',5)
            writetable(app.BestCleaveGroup2_IM,FULLPATH,'Sheet',6)
            writetable(app.CrossCalculatedInSb,FULLPATH,'Sheet',7)
            writetable(app.CrossCalculatedMCT,FULLPATH,'Sheet',8)
            writetable(app.HOI_T,FULLPATH,'Sheet',9)
            writetable(app.HOM_T,FULLPATH,'Sheet',10)
            writetable(app.LeastNoisyHOIM_2ato2c,FULLPATH,'Sheet',11)
            writetable(app.OriginalnHOM,FULLPATH,'Sheet',12)
            writetable(app.FinalPlotRaw,FULLPATH,'Sheet',13)
            close(PleaseWait)
        end

        % Menu selected function: FLOutputMenu
        function FLOutputMenuSelected(app, event)
            if isempty(app.FinalPlotRaw)
                uiconfirm(app.MainApp,'No data available to save.','No data!',...
                    'Icon','error','Options',{'OK'},"CancelOption",1);
                return
            else
            end
            
            fileType={'*.xlsx'};
            [filename,pathname]=uiputfile(fileType,'Save File As');
            if filename==0
                return
            end
            filepath=pathname;
            FULLPATH=fullfile(filepath,filename);
            
            PleaseWait=uiprogressdlg(app.MainApp,'Title','Saving',"Indeterminate","on",'Cancelable',"off");
            writetable(app.FinalPlotRaw,FULLPATH,'Sheet','Attenuation')
            close(PleaseWait)
        end

        % Menu selected function: InGaAsMenu_2
        function InGaAsMenu_2Selected(app, event)
            MCTDPC=isempty(app.MCT);
            InSbDPC=isempty(app.InSb);
            InGaAsDPC=isempty(app.InGaAs);
            if sum([MCTDPC,InSbDPC,InGaAsDPC])==3||sum([MCTDPC,InSbDPC,InGaAsDPC])==2
                app.LoadInGaAsData;
            elseif sum([MCTDPC,InSbDPC,InGaAsDPC])==1
                selection=uiconfirm(app.MainApp,'Data cannot be loaded. Delete InSb or MCT data to continue.','Warning!',...
                    'Icon','warning','Options',{'Return','Delete InSb','Delete MCT'},'DefaultOption',1,"CancelOption",1);
                if strcmp(selection,'Return')
                    return
                elseif strcmp(selection,'Delete InSb')
                    app.InSbRaw=[];
                    app.InSb.Wavelength=[];
                    app.InSb.a1=[];
                    app.InSb.b1=[];
                    app.InSb.c1=[];
                    app.InSb.a2=[];
                    app.InSb.b2=[];
                    app.InSb.c2=[];
                    app.InSbLamp.Color='#A9A9A9';
                elseif strcmp(selection,'Delete MCT')
                    app.MCTRaw=[];
                    app.MCT.Wavelength=[];
                    app.MCT.a1=[];
                    app.MCT.b1=[];
                    app.MCT.c1=[];
                    app.MCT.a2=[];
                    app.MCT.b2=[];
                    app.MCT.c2=[];
                    app.MCTLamp.Color='#A9A9A9';
                end
            end
        end

        % Menu selected function: FigureMenu
        function FigureMenuSelected(app, event)
            [file,path]=uiputfile(...
                {'*.jpg,*.jpeg';'*.png';'*.tif,*.tiff'},'Save Figure');
            if file==0
                return
            else
                filename=fullfile(path,file);
                if isfile(filename)
                    % file exists, warn user, give option to overwrite
                    selection=uiconfirm(app.MainApp,'A file with this name already exists in the directory.','Warning!',...
                        'Icon','warning','Options',{'OK','Overwrite'},'DefaultOption',1,"CancelOption",1);
                    if strcmp(selection,'OK')
                        return
                    elseif strcmp(selection,'Overwrite')
                        if strcmp(app.TabGroup.SelectedTab.Title,'Atten')
                            exportgraphics(app.Figures,filename)
                        elseif strcmp(app.TabGroup.SelectedTab.Title,'ComPlot')
                            exportgraphics(app.ComplotAxes,filename)
                        end
                    end
                else
                    % file does not exist
                    if strcmp(app.TabGroup.SelectedTab.Title,'Atten')
                        exportgraphics(app.Figures,filename)
                    elseif strcmp(app.TabGroup.SelectedTab.Title,'ComPlot')
                        exportgraphics(app.ComplotAxes,filename)
                    end
                end
            end
        end

        % Menu selected function: PlotExcelMenu
        function PlotExcelMenuSelected(app, event)
            app.t=app.LoadData;
            if  app.Complotfilename==0
                return
            end
            if isempty(app.plotNum)
                app.plotNum=0;
            end
            
            app.itemsSize=size(app.PlotListListBox.Items,1);
            [~,name,~]=fileparts(app.PlotTextBox);
            
%             if isempty(t)
            if isempty(app.PlotListListBox.Items) 
                newPlotNum=app.plotNum+1;
                ItemName=join(['Plot ',num2str(newPlotNum), ': ',name],'');
                app.PlotListListBox.Items={ItemName};
                app.PlotListListBox.ItemsData={[app.XAXIS,app.YAXIS]};
            else
                newPlotNum=app.itemsSize+1;
                ItemName=join(['Plot ',num2str(newPlotNum), ': ',name],'');
                a=app.PlotListListBox.Items;
                app.PlotListListBox.Items=[a,{ItemName}];
                b=app.PlotListListBox.ItemsData;
                app.PlotListListBox.ItemsData=[b,{[app.XAXIS,app.YAXIS]}];
            end
%             else
%                 app.PlotListListBox.Items={join(['Plot ',num2str(app.itemsSize+1), ': ',name],'')};
%                 app.PlotListListBox.ItemsData={t};
%             end 
            
            app.itemsSize=size(app.PlotListListBox.Items);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            
            if isempty(app.PlotListListBox.Items)
                uiconfirm(app.MainApp,'No plotting data available','No data!',...
                    'Icon','error','Options',{'OK'},"CancelOption",1);
                return
            elseif app.itemsSize(2)==1
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                d=app.PlotListListBox.ItemsData;
                xyData=table2array(d{1,1});
                hold(app.ComplotAxes,'on')
                plot(app.ComplotAxes,xyData(:,1),xyData(:,2));
                hold(app.ComplotAxes,'off')
                close(PleaseWait)
            elseif app.itemsSize(2)==2
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                hold(app.ComplotAxes,'on')
                d=app.PlotListListBox.ItemsData;
                d1=table2array(d{1,1});d2=table2array(d{1,2});
                xyData=[d1,d2];
                cla(app.ComplotAxes)
                plot(app.ComplotAxes,xyData(:,1),xyData(:,2));
                plot(app.ComplotAxes,xyData(:,3),xyData(:,4));
                hold(app.ComplotAxes,'off')
                close(PleaseWait)
            elseif app.itemsSize(2)==3
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                hold(app.ComplotAxes,'on')
                d=app.PlotListListBox.ItemsData;
                d1=table2array(d{1,1});d2=table2array(d{1,2});d3=table2array(d{1,3});
                xyData=[d1,d2,d3];
                cla(app.ComplotAxes)
                plot(app.ComplotAxes,xyData(:,1),xyData(:,2));
                plot(app.ComplotAxes,xyData(:,3),xyData(:,4));
                plot(app.ComplotAxes,xyData(:,5),xyData(:,6));
                hold(app.ComplotAxes,'off')
                close(PleaseWait)
            elseif app.itemsSize(2)==4
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                hold(app.ComplotAxes,'on')
                d=app.PlotListListBox.ItemsData;
                d1=table2array(d{1,1});d2=table2array(d{1,2});d3=table2array(d{1,3});d4=table2array(d{1,4});
                xyData=[d1,d2,d3,d4];
                cla(app.ComplotAxes)
                plot(app.ComplotAxes,xyData(:,1),xyData(:,2));
                plot(app.ComplotAxes,xyData(:,3),xyData(:,4));
                plot(app.ComplotAxes,xyData(:,5),xyData(:,6));
                plot(app.ComplotAxes,xyData(:,7),xyData(:,8));
                hold(app.ComplotAxes,'off')
                close(PleaseWait)
            elseif app.itemsSize(2)==5
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                hold(app.ComplotAxes,'on')
                d=app.PlotListListBox.ItemsData;
                d1=table2array(d{1,1});d2=table2array(d{1,2});d3=table2array(d{1,3});d4=table2array(d{1,4});
                d5=table2array(d{1,5});
                xyData=[d1,d2,d3,d4,d5];
                cla(app.ComplotAxes)
                plot(app.ComplotAxes,xyData(:,1),xyData(:,2));
                plot(app.ComplotAxes,xyData(:,3),xyData(:,4));
                plot(app.ComplotAxes,xyData(:,5),xyData(:,6));
                plot(app.ComplotAxes,xyData(:,7),xyData(:,8));
                plot(app.ComplotAxes,xyData(:,9),xyData(:,10));
                hold(app.ComplotAxes,'off')
                close(PleaseWait)
            elseif app.itemsSize(2)==6
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                hold(app.ComplotAxes,'on')
                d=app.PlotListListBox.ItemsData;
                d1=table2array(d{1,1});d2=table2array(d{1,2});d3=table2array(d{1,3});d4=table2array(d{1,4});
                d5=table2array(d{1,5});d6=table2array(d{1,6});
                xyData=[d1,d2,d3,d4,d5,d6];
                cla(app.ComplotAxes)
                plot(app.ComplotAxes,xyData(:,1),xyData(:,2));
                plot(app.ComplotAxes,xyData(:,3),xyData(:,4));
                plot(app.ComplotAxes,xyData(:,5),xyData(:,6));
                plot(app.ComplotAxes,xyData(:,7),xyData(:,8));
                plot(app.ComplotAxes,xyData(:,9),xyData(:,10));
                plot(app.ComplotAxes,xyData(:,11),xyData(:,12));
                hold(app.ComplotAxes,'off')
                close(PleaseWait)
            elseif app.itemsSize(2)==7
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                hold(app.ComplotAxes,'on')
                d=app.PlotListListBox.ItemsData;
                d1=table2array(d{1,1});d2=table2array(d{1,2});d3=table2array(d{1,3});d4=table2array(d{1,4});
                d5=table2array(d{1,5});d6=table2array(d{1,6});d7=table2array(d{1,7});
                xyData=[d1,d2,d3,d4,d5,d6,d7];
                cla(app.ComplotAxes)
                plot(app.ComplotAxes,xyData(:,1),xyData(:,2));
                plot(app.ComplotAxes,xyData(:,3),xyData(:,4));
                plot(app.ComplotAxes,xyData(:,5),xyData(:,6));
                plot(app.ComplotAxes,xyData(:,7),xyData(:,8));
                plot(app.ComplotAxes,xyData(:,9),xyData(:,10));
                plot(app.ComplotAxes,xyData(:,11),xyData(:,12));
                plot(app.ComplotAxes,xyData(:,13),xyData(:,14));
                hold(app.ComplotAxes,'off')
                close(PleaseWait)
            elseif app.itemsSize(2)==8
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                hold(app.ComplotAxes,'on')
                d=app.PlotListListBox.ItemsData;
                d1=table2array(d{1,1});d2=table2array(d{1,2});d3=table2array(d{1,3});d4=table2array(d{1,4});
                d5=table2array(d{1,5});d6=table2array(d{1,6});d7=table2array(d{1,7});d8=table2array(d{1,8});
                xyData=[d1,d2,d3,d4,d5,d6,d7,d8];
                cla(app.ComplotAxes)
                plot(app.ComplotAxes,xyData(:,1),xyData(:,2));
                plot(app.ComplotAxes,xyData(:,3),xyData(:,4));
                plot(app.ComplotAxes,xyData(:,5),xyData(:,6));
                plot(app.ComplotAxes,xyData(:,7),xyData(:,8));
                plot(app.ComplotAxes,xyData(:,9),xyData(:,10));
                plot(app.ComplotAxes,xyData(:,11),xyData(:,12));
                plot(app.ComplotAxes,xyData(:,13),xyData(:,14));
                plot(app.ComplotAxes,xyData(:,15),xyData(:,16));
                hold(app.ComplotAxes,'off')
                close(PleaseWait)
            elseif app.itemsSize(2)==9
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                hold(app.ComplotAxes,'on')
                d=app.PlotListListBox.ItemsData;
                d1=table2array(d{1,1});d2=table2array(d{1,2});d3=table2array(d{1,3});d4=table2array(d{1,4});
                d5=table2array(d{1,5});d6=table2array(d{1,6});d7=table2array(d{1,7});d8=table2array(d{1,8});
                d9=table2array(d{1,9});
                xyData=[d1,d2,d3,d4,d5,d6,d7,d8,d9];
                cla(app.ComplotAxes)
                plot(app.ComplotAxes,xyData(:,1),xyData(:,2));
                plot(app.ComplotAxes,xyData(:,3),xyData(:,4));
                plot(app.ComplotAxes,xyData(:,5),xyData(:,6));
                plot(app.ComplotAxes,xyData(:,7),xyData(:,8));
                plot(app.ComplotAxes,xyData(:,9),xyData(:,10));
                plot(app.ComplotAxes,xyData(:,11),xyData(:,12));
                plot(app.ComplotAxes,xyData(:,13),xyData(:,14));
                plot(app.ComplotAxes,xyData(:,15),xyData(:,16));
                plot(app.ComplotAxes,xyData(:,17),xyData(:,18));
                hold(app.ComplotAxes,'off')
                close(PleaseWait)
            elseif app.itemsSize(2)==10
                PleaseWait=uiprogressdlg(app.MainApp,'Title','Plotting',"Indeterminate","on",'Cancelable',"off");
                hold(app.ComplotAxes,'on')
                d=app.PlotListListBox.ItemsData;
                d1=table2array(d{1,1});d2=table2array(d{1,2});d3=table2array(d{1,3});d4=table2array(d{1,4});
                d5=table2array(d{1,5});d6=table2array(d{1,6});d7=table2array(d{1,7});d8=table2array(d{1,8});
                d9=table2array(d{1,9});d10=table2array(d{1,10});
                xyData=[d1,d2,d3,d4,d5,d6,d7,d8,d9,d10];
                cla(app.ComplotAxes)
                plot(app.ComplotAxes,xyData(:,1),xyData(:,2));
                plot(app.ComplotAxes,xyData(:,3),xyData(:,4));
                plot(app.ComplotAxes,xyData(:,5),xyData(:,6));
                plot(app.ComplotAxes,xyData(:,7),xyData(:,8));
                plot(app.ComplotAxes,xyData(:,9),xyData(:,10));
                plot(app.ComplotAxes,xyData(:,11),xyData(:,12));
                plot(app.ComplotAxes,xyData(:,13),xyData(:,14));
                plot(app.ComplotAxes,xyData(:,15),xyData(:,16));
                plot(app.ComplotAxes,xyData(:,17),xyData(:,18));
                plot(app.ComplotAxes,xyData(:,19),xyData(:,20));
                hold(app.ComplotAxes,'off')
                close(PleaseWait)
            end
        end

        % Button pushed function: AxesPropertiesButton
        function AxesPropertiesButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.AxesPropertiesButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.AxesProperties = AxesPropertiesApp(app,app.ComPlotTitle,app.ComplotFontSize, ...
                app.ComplotYLabel,app.ComplotXLabel,app.ComplotLegend,app.ComplotYLim,app.ComplotXLim);
            
        end

        % Close request function: MainApp
        function MainAppCloseRequest(app, event)
            % Delete both apps
            delete(app.AxesProperties)
            delete(app.LoadOpus)
            delete(app)
        end

        % Menu selected function: AllMenu
        function AllMenuSelected(app, event)
            %Atten
            app.OPUSMenu.Enable = 'on';
            if ~isempty(app.FinalPlotRaw)
                app.Figures.Legend.Visible='off';
            end
            cla(app.Figures)
            app.InSbLamp.Color='#A9A9A9';
            app.MCTLamp.Color='#A9A9A9';
            app.InGaAsLamp.Color='#A9A9A9';
            app.Label.Enable='off';
            app.Label.Visible='off';
            app.Figures.Title.String='';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Y';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[0 inf];
            app.Figures.YLim=[0 inf];
            app.InSb=[]; % InSb wavelength after data has been loaded into app
            app.MCT=[]; % MCT wavelength after data has been loaded into app
            app.a1=[]; % Intensity after 1a ''
            app.b1=[]; % Intensity after 1b ''
            app.c1=[]; % Intensity after 1c ''
            app.a2=[]; % Intensity after 2a ''
            app.b2=[]; % Intensity after 2b ''
            app.c2=[]; % Intensity after 2c ''
            app.InSbWavelength=[]; %InSb wavelength converted from table 2 array
            app.MCTWavelength=[]; %MCT wavelength converted from table 2 array
            app.G1aInSb=[]; %Intensity after 1a cleave section of the fibre for InSb detector converted from table 2 array
            app.G1bInSb=[]; %Intensity after 1b ''
            app.G1cInSb=[]; %Intensity after 1c ''
            app.G2aInSb=[]; %Intensity after 2a ''
            app.G2bInSb=[]; %Intensity after 2b ''
            app.G2cInSb=[]; %Intensity after 2c ''
            app.G1aMCT=[]; %Intensity after 1a cleave section of the fibre for MCT detector converted from table 2 array
            app.G1bMCT=[]; %Intensity after 1b ''
            app.G1cMCT=[]; %Intensity after 1c ''
            app.G2aMCT=[]; %Intensity after 2a ''
            app.G2bMCT=[]; %Intensity after 2b ''
            app.G2cMCT=[]; %Intensity after 2c ''
            app.BestGroup1Cleave=[]; % selected by user on main app window
            app.BestGroup2Cleave=[]; % ''
            app.InSb_Best1a2=[]; %
            app.InSb_Best1b2=[]; %
            app.InSb_Best1c2=[]; %
            app.InSb_Best2a1=[]; %
            app.InSb_Best2b1=[]; %
            app.InSb_Best2c1=[]; %
            app.MCT_Best1a2=[]; %
            app.MCT_Best1b2=[]; %
            app.MCT_Best1c2=[]; %
            app.MCT_Best2a1=[]; %
            app.MCT_Best2b1=[]; %
            app.MCT_Best2c1=[]; %
            app.BCG1_I=[]; %
            app.BCG2_I=[]; %
            app.BestG1_LM=[]; %
            app.BestG2_LM=[]; %
            app.BestG1Lable=[]; %
            app.BestG2Lable=[]; %
            app.Lbxnx_m=[];
            app.MatchedMCT=[]; %
            app.CCI_a1a2=[]; %
            app.CCI_a1b2=[]; %
            app.CCI_a1c2=[]; %
            app.CCI_b1a2=[]; %
            app.CCI_b1b2=[]; %
            app.CCI_b1c2=[]; %
            app.CCI_c1a2=[]; %
            app.CCI_c1b2=[]; %
            app.CCI_c1c2=[]; %
            app.CCM_a1a2=[]; %
            app.CCM_a1b2=[]; %
            app.CCM_a1c2=[]; %
            app.CCM_b1a2=[]; %
            app.CCM_b1b2=[]; %
            app.CCM_b1c2=[]; %
            app.CCM_c1a2=[]; %
            app.CCM_c1b2=[]; %
            app.CCM_c1c2=[]; %
            app.CCI_Mean=[];
            app.CCI_Mean2=[];
            app.CCM_Mean=[];
            app.CCI_SD=[]; %
            app.CCM_SD=[]; %
            app.HOI_a2b2=[]; %
            app.HOI_a2c2=[]; %
            app.HOI_b2c2=[]; %
            app.HOM_a2b2=[]; %
            app.HOM_a2c2=[]; %
            app.HOM_b2c2=[]; %
            app.HOI_a2c2_v1=[]; %
            app.HOM_a2c2_v1=[]; %
            app.InSbWavelength_v1=[]; %
            app.HOM=[]; %
            app.FinalPlot=[];%
            app.FibreDetails=[];%
            app.SelectedFigure=[];%
            app.MinAtten=[];%
            app.MinAtten_Char=[];%
            app.WvlofMinLoss=[];%
            app.WvlofMinLoss_Char=[];%
            app.um_sign=[];%
            app.AllLegends=[];
            app.InSbTableRaw=[]; % Raw InSb Data Intensities uploaded
            app.BestCleaveG1Lengths=[]; % Best Cleave lengths for group 1
            app.BestCleaveG2Lengths=[]; % '' group 2
            app.FL_InSb=[]; % fibre loss for InSb calculations
            app.MCTTableRaw=[]; % Raw MCT data intensities - what the user uploaded
            app.Bst_G1_nd_2_MCTIntnstyBsdOnClv=[]; %  best group 1 and group 2 MCT intensity based on best cleave
            app.MCTBLables=[]; % MCT best cleave labels
            app.FL_MCT=[]; % fibre loss for MCT calculations
            app.BestCleaveGroup1_IM=[]; % InSb and MCT plots for the best cleave in group 1
            app.BestCleaveGroup2_IM=[]; % '' group 2
            app.CrossCalculatedInSb=[]; % Cross Calculated Raw InSb
            app.CrossCalculatedMCT=[]; % Cross Calculated Raw MCT
            app.HOI_T=[]; % Healed overloaded bands for InSb
            app.HOM_T=[]; % '' for MCT
            app.LeastNoisyHOIM_2ato2c=[]; % Raw least noisy Healed overloaded bands for InSb and MCT assumed to be 2a to 2c
            app.OriginalnHOM=[]; % original loss (unhealed) vs healed overloaded MCT plots (which has been matched to the base of the original loss)
            app.FinalPlotRaw=[]; % the raw data from the final plot
            app.BestClvG1Lengths=[];
            app.BestClvG2Lengths=[];
            app.BestCleaveData=[];
            app.BestGroup2CleaveLabel=[];
            app.a1_EditField.Value=0;
            app.b1_EditField.Value=0;
            app.c1_EditField.Value=0;
            app.a2_EditField.Value=0;
            app.b2_EditField.Value=0;
            app.c2_EditField.Value=0;
            app.CheckBox.Value=0;
            app.CheckBox_2.Value=0;
            app.CheckBox_3.Value=0;
            app.CheckBox_4.Value=0;
            app.CheckBox_5.Value=0;
            app.CheckBox_6.Value=0;
            app.CheckBox_7.Value=0;
            app.CheckBox.Enable='on';
            app.CheckBox_2.Enable='on';
            app.CheckBox_3.Enable='on';
            app.CheckBox_4.Enable='on';
            app.CheckBox_5.Enable='on';
            app.CheckBox_6.Enable='on';
            app.CheckBox_7.Enable='on';
            app.CalculateLossButton.Enable='on';
            %Complot
            if ~isempty(app.PlotListListBox.Items) && ~isempty(app.ComplotAxes.Legend)
                app.ComplotAxes.Legend.Visible='off';
            end
            cla(app.ComplotAxes)
            app.ComplotFontSize=16;
            app.ComplotYLabel='Wavelength ({\mu}m)';
            app.ComplotXLabel='dB/m';
            app.ComplotXLim=[1.5 10.5];
            app.ComplotYLim=0;
            app.ComplotLegend=[];
            app.ComplotAxes.Title.String='';
            app.ComplotAxes.Title.FontWeight='bold';
            app.ComplotAxes.Title.FontSize=16;
            app.ComplotAxes.Title.FontSmoothing='on';
            app.ComplotAxes.YLabel.String='dB/m';
            app.ComplotAxes.XLabel.String='Wavelength ({\mu}m)';
            app.ComplotAxes.XLim=[1.5 10.5];
            app.ComplotAxes.YLim=[0 inf];
            app.AllLegends=[];
            app.AxesPropertiesButton.Enable='on';
            app.AxesPropertiesButton.Enable = 'on';
            for k=size(app.FigureListListBox.Items,2):-1:1
                app.FigureListListBox.ItemsData(k)=[];
                app.FigureListListBox.Items(k)=[];
            end
            for k=size(app.PlotListListBox.Items,2):-1:1
                app.PlotListListBox.ItemsData(k)=[];
                app.PlotListListBox.Items(k)=[];
            end
            
        end

        % Menu selected function: AttenMenu
        function AttenMenuSelected(app, event)
            app.OPUSMenu.Enable = 'on';
            if ~isempty(app.FinalPlotRaw)
                app.Figures.Legend.Visible='off';
            end
            cla(app.Figures)
            app.InSbLamp.Color='#A9A9A9';
            app.MCTLamp.Color='#A9A9A9';
            app.InGaAsLamp.Color='#A9A9A9';
            app.Label.Enable='off';
            app.Label.Visible='off';
            app.Figures.Title.String='';
            app.Figures.Title.FontWeight='bold';
            app.Figures.Title.FontSize=16;
            app.Figures.Title.FontSmoothing='on';
            app.Figures.YLabel.String='Y';
            app.Figures.XLabel.String='Wavelength ({\mu}m)';
            app.Figures.XLim=[0 inf];
            app.Figures.YLim=[0 inf];
            app.InSb=[]; % InSb wavelength after data has been loaded into app
            app.MCT=[]; % MCT wavelength after data has been loaded into app
            app.a1=[]; % Intensity after 1a ''
            app.b1=[]; % Intensity after 1b ''
            app.c1=[]; % Intensity after 1c ''
            app.a2=[]; % Intensity after 2a ''
            app.b2=[]; % Intensity after 2b ''
            app.c2=[]; % Intensity after 2c ''
            app.InSbWavelength=[]; %InSb wavelength converted from table 2 array
            app.MCTWavelength=[]; %MCT wavelength converted from table 2 array
            app.G1aInSb=[]; %Intensity after 1a cleave section of the fibre for InSb detector converted from table 2 array
            app.G1bInSb=[]; %Intensity after 1b ''
            app.G1cInSb=[]; %Intensity after 1c ''
            app.G2aInSb=[]; %Intensity after 2a ''
            app.G2bInSb=[]; %Intensity after 2b ''
            app.G2cInSb=[]; %Intensity after 2c ''
            app.G1aMCT=[]; %Intensity after 1a cleave section of the fibre for MCT detector converted from table 2 array
            app.G1bMCT=[]; %Intensity after 1b ''
            app.G1cMCT=[]; %Intensity after 1c ''
            app.G2aMCT=[]; %Intensity after 2a ''
            app.G2bMCT=[]; %Intensity after 2b ''
            app.G2cMCT=[]; %Intensity after 2c ''
            app.BestGroup1Cleave=[]; % selected by user on main app window
            app.BestGroup2Cleave=[]; % ''
            app.InSb_Best1a2=[]; %
            app.InSb_Best1b2=[]; %
            app.InSb_Best1c2=[]; %
            app.InSb_Best2a1=[]; %
            app.InSb_Best2b1=[]; %
            app.InSb_Best2c1=[]; %
            app.MCT_Best1a2=[]; %
            app.MCT_Best1b2=[]; %
            app.MCT_Best1c2=[]; %
            app.MCT_Best2a1=[]; %
            app.MCT_Best2b1=[]; %
            app.MCT_Best2c1=[]; %
            app.BCG1_I=[]; %
            app.BCG2_I=[]; %
            app.BestG1_LM=[]; %
            app.BestG2_LM=[]; %
            app.BestG1Lable=[]; %
            app.BestG2Lable=[]; %
            app.Lbxnx_m=[];
            app.MatchedMCT=[]; %
            app.CCI_a1a2=[]; %
            app.CCI_a1b2=[]; %
            app.CCI_a1c2=[]; %
            app.CCI_b1a2=[]; %
            app.CCI_b1b2=[]; %
            app.CCI_b1c2=[]; %
            app.CCI_c1a2=[]; %
            app.CCI_c1b2=[]; %
            app.CCI_c1c2=[]; %
            app.CCM_a1a2=[]; %
            app.CCM_a1b2=[]; %
            app.CCM_a1c2=[]; %
            app.CCM_b1a2=[]; %
            app.CCM_b1b2=[]; %
            app.CCM_b1c2=[]; %
            app.CCM_c1a2=[]; %
            app.CCM_c1b2=[]; %
            app.CCM_c1c2=[]; %
            app.CCI_Mean=[];
            app.CCI_Mean2=[];
            app.CCM_Mean=[];
            app.CCI_SD=[]; %
            app.CCM_SD=[]; %
            app.HOI_a2b2=[]; %
            app.HOI_a2c2=[]; %
            app.HOI_b2c2=[]; %
            app.HOM_a2b2=[]; %
            app.HOM_a2c2=[]; %
            app.HOM_b2c2=[]; %
            app.HOI_a2c2_v1=[]; %
            app.HOM_a2c2_v1=[]; %
            app.InSbWavelength_v1=[]; %
            app.HOM=[]; %
            app.FinalPlot=[];%
            app.FibreDetails=[];%
            app.SelectedFigure=[];%
            app.MinAtten=[];%
            app.MinAtten_Char=[];%
            app.WvlofMinLoss=[];%
            app.WvlofMinLoss_Char=[];%
            app.um_sign=[];%
            app.AllLegends=[];
            app.InSbTableRaw=[]; % Raw InSb Data Intensities uploaded
            app.BestCleaveG1Lengths=[]; % Best Cleave lengths for group 1
            app.BestCleaveG2Lengths=[]; % '' group 2
            app.FL_InSb=[]; % fibre loss for InSb calculations
            app.MCTTableRaw=[]; % Raw MCT data intensities - what the user uploaded
            app.Bst_G1_nd_2_MCTIntnstyBsdOnClv=[]; %  best group 1 and group 2 MCT intensity based on best cleave
            app.MCTBLables=[]; % MCT best cleave labels
            app.FL_MCT=[]; % fibre loss for MCT calculations
            app.BestCleaveGroup1_IM=[]; % InSb and MCT plots for the best cleave in group 1
            app.BestCleaveGroup2_IM=[]; % '' group 2
            app.CrossCalculatedInSb=[]; % Cross Calculated Raw InSb
            app.CrossCalculatedMCT=[]; % Cross Calculated Raw MCT
            app.HOI_T=[]; % Healed overloaded bands for InSb
            app.HOM_T=[]; % '' for MCT
            app.LeastNoisyHOIM_2ato2c=[]; % Raw least noisy Healed overloaded bands for InSb and MCT assumed to be 2a to 2c
            app.OriginalnHOM=[]; % original loss (unhealed) vs healed overloaded MCT plots (which has been matched to the base of the original loss)
            app.FinalPlotRaw=[]; % the raw data from the final plot
            app.BestClvG1Lengths=[];
            app.BestClvG2Lengths=[];
            app.BestCleaveData=[];
            app.BestGroup2CleaveLabel=[];
            app.a1_EditField.Value=0;
            app.b1_EditField.Value=0;
            app.c1_EditField.Value=0;
            app.a2_EditField.Value=0;
            app.b2_EditField.Value=0;
            app.c2_EditField.Value=0;
            app.CheckBox.Value=0;
            app.CheckBox_2.Value=0;
            app.CheckBox_3.Value=0;
            app.CheckBox_4.Value=0;
            app.CheckBox_5.Value=0;
            app.CheckBox_6.Value=0;
            app.CheckBox_7.Value=0;
            app.CheckBox.Enable='on';
            app.CheckBox_2.Enable='on';
            app.CheckBox_3.Enable='on';
            app.CheckBox_4.Enable='on';
            app.CheckBox_5.Enable='on';
            app.CheckBox_6.Enable='on';
            app.CheckBox_7.Enable='on';
            app.CalculateLossButton.Enable='on';
            for k=size(app.FigureListListBox.Items,2):-1:1
                app.FigureListListBox.ItemsData(k)=[];
                app.FigureListListBox.Items(k)=[];
            end
        end

        % Menu selected function: ComplotMenu
        function ComplotMenuSelected(app, event)
            if ~isempty(app.PlotListListBox.Items) && ~isempty(app.ComplotAxes.Legend)
                app.ComplotAxes.Legend.Visible='off';
            end
            cla(app.ComplotAxes)
            app.ComplotFontSize=16;
            app.ComplotYLabel='Wavelength ({\mu}m)';
            app.ComplotXLabel='dB/m';
            app.ComplotXLim=[1.5 10.5];
            app.ComplotYLim=0;
            app.ComplotLegend=[];
            app.ComplotAxes.Title.String='';
            app.ComplotAxes.Title.FontWeight='bold';
            app.ComplotAxes.Title.FontSize=16;
            app.ComplotAxes.Title.FontSmoothing='on';
            app.ComplotAxes.YLabel.String='dB/m';
            app.ComplotAxes.XLabel.String='Wavelength ({\mu}m)';
            app.ComplotAxes.XLim=[1.5 10.5];
            app.ComplotAxes.YLim=[0 inf];
            app.AllLegends=[];
            app.AxesPropertiesButton.Enable='on';
            app.AxesPropertiesButton.Enable = 'on';
            for k=size(app.PlotListListBox.Items,2):-1:1
                app.PlotListListBox.ItemsData(k)=[];
                app.PlotListListBox.Items(k)=[];
            end
        end

        % Menu selected function: OPUSMenu
        function OPUSMenuSelected(app, event)
            % Disable Plot Options button while dialog is open
            app.OPUSMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.LoadOpus = LoadOpusApp(app,app.InSb_transfer,app.MCT_transfer,app.InGaAs_transfer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainApp = uifigure('Visible', 'off');
            app.MainApp.Name = 'Atten';
            app.MainApp.Icon = 'atten icon.png';
            app.MainApp.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.Menu = uimenu(app.MainApp);
            app.Menu.Text = 'Menu';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.Text = 'Save';
            app.FigureMenu = uimenu(app.SaveMenu);
            app.FigureMenu.MenuSelectedFcn = createCallbackFcn(app, @FigureMenuSelected, true);
            app.FigureMenu.Text = 'Figure';
            app.DataOutputMenu = uimenu(app.SaveMenu);
            app.DataOutputMenu.Text = 'Data Output';
            app.AllDataMenu = uimenu(app.DataOutputMenu);
            app.AllDataMenu.MenuSelectedFcn = createCallbackFcn(app, @AllDataMenuSelected, true);
            app.AllDataMenu.Text = 'All Data';
            app.FLOutputMenu = uimenu(app.DataOutputMenu);
            app.FLOutputMenu.MenuSelectedFcn = createCallbackFcn(app, @FLOutputMenuSelected, true);
            app.FLOutputMenu.Text = 'FL Output';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.Text = 'Load';
            app.ComPlotMenu_2 = uimenu(app.LoadMenu);
            app.ComPlotMenu_2.Text = 'ComPlot';
            app.PlotExcelMenu = uimenu(app.ComPlotMenu_2);
            app.PlotExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotExcelMenuSelected, true);
            app.PlotExcelMenu.Text = 'Plot (Excel)';
            app.InputDataMenu = uimenu(app.LoadMenu);
            app.InputDataMenu.Text = 'Input Data';
            app.OPUSMenu = uimenu(app.InputDataMenu);
            app.OPUSMenu.MenuSelectedFcn = createCallbackFcn(app, @OPUSMenuSelected, true);
            app.OPUSMenu.Text = 'OPUS';
            app.ExcelMenu = uimenu(app.InputDataMenu);
            app.ExcelMenu.Text = 'Excel';
            app.InSbMenu_2 = uimenu(app.ExcelMenu);
            app.InSbMenu_2.MenuSelectedFcn = createCallbackFcn(app, @InSbMenu_2Selected, true);
            app.InSbMenu_2.Text = 'InSb';
            app.MCTMenu_2 = uimenu(app.ExcelMenu);
            app.MCTMenu_2.MenuSelectedFcn = createCallbackFcn(app, @MCTMenu_2Selected, true);
            app.MCTMenu_2.Text = 'MCT';
            app.InGaAsMenu_2 = uimenu(app.ExcelMenu);
            app.InGaAsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @InGaAsMenu_2Selected, true);
            app.InGaAsMenu_2.Text = 'InGaAs';
            app.ResetMenu = uimenu(app.Menu);
            app.ResetMenu.Text = 'Reset';
            app.AttenMenu = uimenu(app.ResetMenu);
            app.AttenMenu.MenuSelectedFcn = createCallbackFcn(app, @AttenMenuSelected, true);
            app.AttenMenu.Text = 'Atten';
            app.ComplotMenu = uimenu(app.ResetMenu);
            app.ComplotMenu.MenuSelectedFcn = createCallbackFcn(app, @ComplotMenuSelected, true);
            app.ComplotMenu.Text = 'Complot';
            app.AllMenu = uimenu(app.ResetMenu);
            app.AllMenu.MenuSelectedFcn = createCallbackFcn(app, @AllMenuSelected, true);
            app.AllMenu.Text = 'All';
            app.TabGroup = uitabgroup(app.MainApp);
            app.AttenTab = uitab(app.TabGroup);
            app.AttenTab.Title = 'Atten';
            app.Label = uilabel(app.AttenTab);
            app.aLabel = uilabel(app.AttenTab);
            app.aLabel.Text = 'a';
            app.cLabel_2 = uilabel(app.AttenTab);
            app.cLabel_2.Text = 'c';
            app.bLabel_2 = uilabel(app.AttenTab);
            app.bLabel_2.Text = 'b';
            app.aLabel_2 = uilabel(app.AttenTab);
            app.aLabel_2.Text = 'a';
            app.cLabel = uilabel(app.AttenTab);
            app.cLabel.Text = 'c';
            app.bLabel = uilabel(app.AttenTab);
            app.bLabel.Text = 'b';
            app.CheckBox_6 = uicheckbox(app.AttenTab);
            app.CheckBox_6.Text = '';
            app.CheckBox_5 = uicheckbox(app.AttenTab);
            app.CheckBox_5.Text = '';
            app.CheckBox_4 = uicheckbox(app.AttenTab);
            app.CheckBox_4.Text = '';
            app.CheckBox_3 = uicheckbox(app.AttenTab);
            app.CheckBox_3.Text = '';
            app.CheckBox_2 = uicheckbox(app.AttenTab);
            app.CheckBox_2.Text = '';
            app.CheckBox = uicheckbox(app.AttenTab);
            app.CheckBox.Text = '';
            app.CalculateLossButton = uibutton(app.AttenTab, 'push');
            app.CalculateLossButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateLossButtonPushed, true);
            app.CalculateLossButton.Text = 'Calculate Loss';
            app.G2Label = uilabel(app.AttenTab);
            app.G2Label.Text = 'G2';
            app.G1Label = uilabel(app.AttenTab);
            app.G1Label.Text = 'G1';
            app.FibreLengthsLabel = uilabel(app.AttenTab);
            app.FibreLengthsLabel.Text = 'Fibre Lengths ';
            app.FigureListListBox = uilistbox(app.AttenTab);
            app.FigureListListBox.Items = {};
            app.FigureListListBox.ValueChangedFcn = createCallbackFcn(app, @FigureListListBoxValueChanged, true);
            app.FigureListListBox.Value = {};
            app.FigureListListBoxLabel = uilabel(app.AttenTab);
            app.FigureListListBoxLabel.Text = 'Figure List';
            app.AutoBCLabel = uilabel(app.AttenTab);
            app.AutoBCLabel.Text = 'Auto BC';
            app.CheckBox_7 = uicheckbox(app.AttenTab);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '';
            app.InSbLamp = uilamp(app.AttenTab);
            app.InSbLabel = uilabel(app.AttenTab);
            app.InSbLabel.Text = 'InSb';
            app.MCTLamp = uilamp(app.AttenTab);
            app.MCTLabel = uilabel(app.AttenTab);
            app.MCTLabel.Text = 'MCT';
            app.InGaAsLamp = uilamp(app.AttenTab);
            app.InGaAsLabel = uilabel(app.AttenTab);
            app.InGaAsLabel.Text = 'InGaAs';
            app.InputDataLabel = uilabel(app.AttenTab);
            app.InputDataLabel.Text = 'Input Data';
            app.AttenLabel = uilabel(app.AttenTab);
            app.AttenLabel.Text = 'Atten';
            app.a1_EditField = uieditfield(app.AttenTab, 'numeric');
            app.a1_EditField.Limits = [0 Inf];
            app.a1_EditField.ValueDisplayFormat = '%.2f';
            app.a1_EditField.Editable = 'off';
            app.b1_EditField = uieditfield(app.AttenTab, 'numeric');
            app.b1_EditField.Limits = [0 Inf];
            app.b1_EditField.ValueDisplayFormat = '%.2f';
            app.c1_EditField = uieditfield(app.AttenTab, 'numeric');
            app.c1_EditField.Limits = [0 Inf];
            app.c1_EditField.ValueDisplayFormat = '%.2f';
            app.a2_EditField = uieditfield(app.AttenTab, 'numeric');
            app.a2_EditField.Limits = [0 Inf];
            app.a2_EditField.ValueDisplayFormat = '%.2f';
            app.b2_EditField = uieditfield(app.AttenTab, 'numeric');
            app.b2_EditField.Limits = [0 Inf];
            app.b2_EditField.ValueDisplayFormat = '%.2f';
            app.c2_EditField = uieditfield(app.AttenTab, 'numeric');
            app.c2_EditField.Limits = [0 Inf];
            app.c2_EditField.ValueDisplayFormat = '%.2f';
            app.Figures = uiaxes(app.AttenTab);
            title(app.Figures, 'Title')
            xlabel(app.Figures, 'Wavelength ({\mu}m)')
            ylabel(app.Figures, 'Y')
            app.ComPlotTab = uitab(app.TabGroup);
            app.ComPlotTab.Title = 'ComPlot';
            app.ComPlotLabel = uilabel(app.ComPlotTab);
            app.ComPlotLabel.Text = 'ComPlot';
            app.DeleteAllDataButton = uibutton(app.ComPlotTab, 'push');
            app.DeleteAllDataButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllDataButtonPushed, true);
            app.DeleteAllDataButton.Text = 'Delete All Data';
            app.ResetFigureButton = uibutton(app.ComPlotTab, 'push');
            app.ResetFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFigureButtonPushed, true);
            app.ResetFigureButton.Text = 'Reset Figure';
            app.PlotListListBox = uilistbox(app.ComPlotTab);
            app.PlotListListBox.Items = {};
            app.PlotListListBox.Value = {};
            app.PlotButton = uibutton(app.ComPlotTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.AxesPropertiesButton = uibutton(app.ComPlotTab, 'push');
            app.AxesPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @AxesPropertiesButtonPushed, true);
            app.AxesPropertiesButton.Text = 'Axes Properties';
            app.ComplotAxes = uiaxes(app.ComPlotTab);
            title(app.ComplotAxes, 'Title')
            xlabel(app.ComplotAxes, 'X')
            ylabel(app.ComplotAxes, 'Y')
            % Show the figure after all components are created
            app.MainApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Atten_ComPlot_V3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainApp)
        end
    end
end