classdef AxesPropertiesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AxesProperties           matlab.ui.Figure
        AxesPropertiesLabel      matlab.ui.control.Label
        AxesTitleEditFieldLabel  matlab.ui.control.Label
        AxesTitle                matlab.ui.control.EditField
        XLabelEditFieldLabel     matlab.ui.control.Label
        XLabel                   matlab.ui.control.EditField
        YLabelEditFieldLabel     matlab.ui.control.Label
        YLabel                   matlab.ui.control.EditField
        LegendSeparateeachentrywithacommaTextAreaLabel  matlab.ui.control.Label
        LegendTextArea           matlab.ui.control.TextArea
        OKButton                 matlab.ui.control.Button
        FontSizeLabel            matlab.ui.control.Label
        FontSize                 matlab.ui.control.NumericEditField
        XLimEg110Label           matlab.ui.control.Label
        XLim                     matlab.ui.control.EditField
        YLimEg0infLabel          matlab.ui.control.Label
        YLim                     matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ct, cfs, cyl, cxl, cl, cylm, cxlm)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            if isempty(cl)
                lgd='';
            else
                lgd=cl;
            end
            xlm=join([string(cxlm(1)),string(cxlm(2))],',');
            ylm=join([string(cylm(1)),string(cylm(2))],',');
            % Update UI with input values
            app.AxesTitle.Value = ct;
            app.XLabel.Value = cxl;
            app.YLabel.Value = string(cyl);
            app.FontSize.Value = cfs;
            app.LegendTextArea.Value=lgd;
            app.YLim.Value=ylm;
            app.XLim.Value=xlm;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateplot(app.CallingApp,app.AxesTitle.Value,app.FontSize.Value,...
                app.YLabel.Value,app.XLabel.Value,app.LegendTextArea.Value,app.XLim.Value,app.YLim.Value)
            % Delete the dialog box
            delete(app)
        end

        % Close request function: AxesProperties
        function AxesPropertiesCloseRequest(app, event)
            % Delete the dialog box 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AxesProperties = uifigure('Visible', 'off');
            app.AxesProperties.Name = 'Axes Properties';
            app.AxesProperties.Icon = 'atten icon.png';
            app.AxesProperties.CloseRequestFcn = createCallbackFcn(app, @AxesPropertiesCloseRequest, true);
            app.AxesPropertiesLabel = uilabel(app.AxesProperties);
            app.AxesPropertiesLabel.Text = 'Axes Properties';
            app.AxesTitleEditFieldLabel = uilabel(app.AxesProperties);
            app.AxesTitleEditFieldLabel.Text = 'Axes Title';
            app.AxesTitle = uieditfield(app.AxesProperties, 'text');
            app.XLabelEditFieldLabel = uilabel(app.AxesProperties);
            app.XLabelEditFieldLabel.Text = 'X-Label';
            app.XLabel = uieditfield(app.AxesProperties, 'text');
            app.YLabelEditFieldLabel = uilabel(app.AxesProperties);
            app.YLabelEditFieldLabel.Text = 'Y-Label';
            app.YLabel = uieditfield(app.AxesProperties, 'text');
            app.LegendSeparateeachentrywithacommaTextAreaLabel = uilabel(app.AxesProperties);
            app.LegendSeparateeachentrywithacommaTextAreaLabel.Text = 'Legend: Separate each entry with a comma.';
            app.LegendTextArea = uitextarea(app.AxesProperties);
            app.OKButton = uibutton(app.AxesProperties, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.FontSizeLabel = uilabel(app.AxesProperties);
            app.FontSizeLabel.Text = 'Font Size';
            app.FontSize = uieditfield(app.AxesProperties, 'numeric');
            app.XLimEg110Label = uilabel(app.AxesProperties);
            app.XLimEg110Label.Text = 'X-Lim (E.g 1,10)';
            app.XLim = uieditfield(app.AxesProperties, 'text');
            app.YLimEg0infLabel = uilabel(app.AxesProperties);
            app.YLimEg0infLabel.Text = 'Y-Lim (E.g. 0,inf)';
            app.YLim = uieditfield(app.AxesProperties, 'text');
            % Show the figure after all components are created
            app.AxesProperties.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AxesPropertiesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AxesProperties)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AxesProperties)
        end
    end
end