classdef LoadOpusApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadOpusUIFigure              matlab.ui.Figure
        LoadMenu_2                    matlab.ui.container.Menu
        LoadDataLabel                 matlab.ui.control.Label
        LoadedInSbDataListBoxLabel    matlab.ui.control.Label
        LoadedInSbDataListBox         matlab.ui.control.ListBox
        InSbLamp                      matlab.ui.control.Lamp
        LoadedMCTDataListBoxLabel     matlab.ui.control.Label
        LoadedMCTDataListBox          matlab.ui.control.ListBox
        MCTLamp                       matlab.ui.control.Lamp
        LoadedInGaAsDataListBoxLabel  matlab.ui.control.Label
        LoadedInGaAsDataListBox       matlab.ui.control.ListBox
        Label                         matlab.ui.control.Label
        InGaAsLamp                    matlab.ui.control.Lamp
        TransferButton                matlab.ui.control.Button
        LoadingStatusTextAreaLabel    matlab.ui.control.Label
        LoadingStatusTextArea         matlab.ui.control.TextArea
    end


    properties (Access = public)
        CallingApp % mainapp
    end
    methods
        
        %===========================================================================================================
        %===========================================================================================================
        
        function ClearApp(app)
            app.LoadingStatusTextArea.Value='';
            for k=size(app.LoadedInSbDataListBox.Items,2):-1:1
                app.LoadedInSbDataListBox.ItemsData(k)=[];
                app.LoadedInSbDataListBox.Items(k)=[];
                app.InSbLamp.Color='#A9A9A9';
            end
            for k=size(app.LoadedMCTDataListBox.Items,2):-1:1
                app.LoadedMCTDataListBox.ItemsData(k)=[];
                app.LoadedMCTDataListBox.Items(k)=[];
                app.MCTLamp.Color='#A9A9A9';
            end
            for k=size(app.LoadedInGaAsDataListBox.Items,2):-1:1
                app.LoadedInGaAsDataListBox.ItemsData(k)=[];
                app.LoadedInGaAsDataListBox.Items(k)=[];
                app.InGaAsLamp.Color='#A9A9A9';
            end
        end
        
        %===========================================================================================================
        %===========================================================================================================
        
        function LoadOPUSData(app)
            if sum([isempty(app.LoadedInSbDataListBox.Items),isempty(app.LoadedMCTDataListBox.Items),...
                    isempty(app.LoadedInGaAsDataListBox.Items)])<=0
                selection=uiconfirm(app.LoadOpusUIFigure,'Data already loaded will be overwritten. ','Warning!',...
                        'Icon','warning','Options',{'Return','Continue'},'DefaultOption',2,"CancelOption",1);
                if strcmp(selection,'Return')
                    return
                else
                    app.ClearApp
                end
            end
            
            if ismac
               path='/home';
            elseif isunix
                path='C:/';
            elseif ispc
                path='C:\';
            end
            folderTitle='Select Your Data Folder';
            selpath=uigetdir(path,folderTitle);
            if selpath==0
                %user clicked the cancel button.
                clear path folderTitle selpath
                return 
            end
            FolderInfo=dir(selpath);
            numFiles=size(FolderInfo,1);
            folExts=strings(size(FolderInfo,1),1);
            filetype=strings(size(FolderInfo,1),1);
            fibreparts=["1a","1b","1c","1d","1e",...
                "2a","2b","2c","2d","2e"];
            InSb="InSb";
            MCT="MCT";
            InGaAs="InGaAs";
            Detector=strings(size(FolderInfo,1),1);
            FName=strings(size(FolderInfo,1),1);
            for k=1:numFiles
                [~,name,ext]=fileparts(FolderInfo(k).name);
                ext=erase(ext,'.');
                strNum=string(0:1e3);
                compStr=strcmp(ext,strNum);
                if sum(compStr)==0
                    filetype(k)=[];
                elseif sum(compStr)>0
                    filetype(k)="OPUS";
                    fNameParts=string(split(name,'_'));
                    fibParts=fNameParts==fibreparts;
                    if sum(sum(fibParts))==0 || sum(sum(fibParts))>1
                        uiconfirm(app.LoadOpusUIFigure,'Files incorrectly named. ','Error!',...
                        'Icon','error','Options',{'Okay'},'DefaultOption',1,"CancelOption",1);
                        return
                    else
                    end 
                    name=string(name);
                    FName(k)=name;
            
                    Det=fNameParts==InSb;
                    if sum(Det)==0
                        Det=fNameParts==MCT;
                    end 
                    if sum(Det)==0
                        Det=fNameParts==InGaAs;
                    end
                    [row1,col1]=find(Det==1);
                    Detector(k)=fNameParts(row1,col1);
                end 
                folExts(k)=string(ext);
            end 
            
            
            % Check for InSb Files
            InSbFilesCheck=zeros(size(FName));
            InSbFiles=strings(size(FName));
            InSbFibreSecCheck=zeros(size(FName,1),size(fibreparts,2));
            InSbFibreParts=strings(size(FName));
            for k=1:size(FName,1)
                InSbFilesCheck(k)=contains(FName(k),InSb);
                if InSbFilesCheck(k)
                    InSbFiles(k)=FName(k);
                    for t=1:size(fibreparts,2)
                        InSbFibreSecCheck(k,t)=contains(InSbFiles(k),fibreparts(t));
                        if InSbFibreSecCheck(k,t)
                            InSbFibreParts(k)=fibreparts(t);
                        end 
                    end 
                end 
                
            end 
            InSbFiles(InSbFiles=="")=[];
            InSbFibreParts(InSbFibreParts=="")=[];
            InSbFibreParts=sort(InSbFibreParts);
            IGA=strlength(InSbFiles);
            [~,X]=ismember(InSbFibreParts,extractAfter(InSbFiles,IGA-2));
            InSbFiles=InSbFiles(X);
            InSbFiles_tab=[InSbFiles,InSbFibreParts];
            
            % Check for MCT Files
            MCTFilesCheck=zeros(size(FName));
            MCTFiles=strings(size(FName));
            MCTFibreSecCheck=zeros(size(FName,1),size(fibreparts,2));
            MCTFibreParts=strings(size(FName));
            for k=1:size(FName,1)
                MCTFilesCheck(k)=contains(FName(k),MCT);
                if MCTFilesCheck(k)
                    MCTFiles(k)=FName(k);
                    for t=1:size(fibreparts,2)
                        MCTFibreSecCheck(k,t)=contains(MCTFiles(k),fibreparts(t));
                        if MCTFibreSecCheck(k,t)
                            MCTFibreParts(k)=fibreparts(t);
                        end 
                    end 
                end 
            end 
            MCTFiles(MCTFiles=="")=[];
            MCTFibreParts(MCTFibreParts=="")=[];
            MCTFibreParts=sort(MCTFibreParts);
            IGA=strlength(MCTFiles);
            [~,X]=ismember(MCTFibreParts,extractAfter(MCTFiles,IGA-2));
            MCTFiles=MCTFiles(X);
            MCTFiles_tab=[MCTFiles,MCTFibreParts];
            
            % Check for InGaAs Files
            InGaAsFilesCheck=zeros(size(FName));
            InGaAsFiles=strings(size(FName));
            InGaAsFibreSecCheck=zeros(size(FName,1),size(fibreparts,2));
            InGaAsFibreParts=strings(size(FName));
            for k=1:size(FName,1)
                InGaAsFilesCheck(k)=contains(FName(k),InGaAs);
                if InGaAsFilesCheck(k)
                    InGaAsFiles(k)=FName(k);
                    for t=1:size(fibreparts,2)
                        InGaAsFibreSecCheck(k,t)=contains(InGaAsFiles(k),fibreparts(t));
                        if InGaAsFibreSecCheck(k,t)
                            InGaAsFibreParts(k)=fibreparts(t);
                        end 
                    end 
                end 
            end 
            InGaAsFiles(InGaAsFiles=="")=[];
            InGaAsFibreParts(InGaAsFibreParts=="")=[];
            InGaAsFibreParts=sort(InGaAsFibreParts);
            IGA=strlength(InGaAsFiles);
            [~,X]=ismember(InGaAsFibreParts,extractAfter(InGaAsFiles,IGA-2));
            InGaAsFiles=InGaAsFiles(X);
            InGaAsFiles_tab=[InGaAsFiles,InGaAsFibreParts];
            
            InSbdetectorCheck=sum(Detector==InSb);
            MCTdetectorCheck=sum(Detector==MCT);
            InGaAsdetectorCheck=sum(Detector==InGaAs);
            app.initialise
            if InSbdetectorCheck>0
                InSbData=app.OPL('filetype','Opus','folderDir',selpath,'filename','*InSb*');
                 for k=1:InSbdetectorCheck
                     app.LoadedInSbDataListBox.Items(k)={char(InSbFiles_tab(k,1))};
                     app.LoadedInSbDataListBox.ItemsData{k}={InSbData.data(k)};
                 end
                 app.InSbLamp.Color='#00FF00';
            end 
            if MCTdetectorCheck>0
                MCTData=app.OPL('filetype','Opus','folderDir',selpath,'filename','*MCT*');
                for k=1:MCTdetectorCheck
                    app.LoadedMCTDataListBox.Items(k)={char(MCTFiles_tab(k,1))};
                    app.LoadedMCTDataListBox.ItemsData{k}={MCTData.data(k)};
                end
                app.MCTLamp.Color='#00FF00';
            end 
            if InGaAsdetectorCheck>0
                InGaAsData=app.OPL('filetype','Opus','folderDir',selpath,'filename','*InGaAs*');
                for k=1:InGaAsdetectorCheck
                    app.LoadedInGaAsDataListBox.Items(k)={char(InGaAsFiles_tab(k,1))};
                    app.LoadedInGaAsDataListBox.ItemsData{k}={InGaAsData.data(k)};
                end
                 app.InGaAsLamp.Color='#00FF00';
            end 
            
        end
        
        %===========================================================================================================
        %===========================================================================================================
        
        function [InSb_transfer,MCT_transfer,InGaAs_transfer]=PackageData(app)
        InSbSelection=size(app.LoadedInSbDataListBox.Value,2);
        MCTSelection=size(app.LoadedMCTDataListBox.Value,2);
        InGaAsSelection=size(app.LoadedInGaAsDataListBox.Value,2);
        
        if sum([InSbSelection,MCTSelection,InGaAsSelection])==18
            uiconfirm(app.LoadOpusUIFigure,'Maximum transferable data files reached (12). ','Error!',...
                'Icon','error','Options',{'Okay'},'DefaultOption',1,"CancelOption",1);
            return
        end 
        
        if sum([InSbSelection,MCTSelection,InGaAsSelection])<18 ...
                && sum([InSbSelection,MCTSelection,InGaAsSelection])>=12
            if InGaAsSelection<6
                %packaging data code for InSb and MCT
                InSb_transfer.InSbWavelength=table(10000./app.LoadedInSbDataListBox.Value{1,1}{1,1}.wavenumber);
                InSb_transfer.InSb_1a=table(app.LoadedInSbDataListBox.Value{1,1}{1,1}.Ratio);
                InSb_transfer.InSb_1b=table(app.LoadedInSbDataListBox.Value{1,2}{1,1}.Ratio);
                InSb_transfer.InSb_1c=table(app.LoadedInSbDataListBox.Value{1,3}{1,1}.Ratio);
                InSb_transfer.InSb_2a=table(app.LoadedInSbDataListBox.Value{1,4}{1,1}.Ratio);
                InSb_transfer.InSb_2b=table(app.LoadedInSbDataListBox.Value{1,5}{1,1}.Ratio);
                InSb_transfer.InSb_2c=table(app.LoadedInSbDataListBox.Value{1,6}{1,1}.Ratio);
                MCT_transfer.MCTWavelength=table(10000./app.LoadedMCTDataListBox.Value{1,1}{1,1}.wavenumber);
                MCT_transfer.MCT_1a=table(app.LoadedMCTDataListBox.Value{1,1}{1,1}.Ratio);
                MCT_transfer.MCT_1b=table(app.LoadedMCTDataListBox.Value{1,2}{1,1}.Ratio);
                MCT_transfer.MCT_1c=table(app.LoadedMCTDataListBox.Value{1,3}{1,1}.Ratio);
                MCT_transfer.MCT_2a=table(app.LoadedMCTDataListBox.Value{1,4}{1,1}.Ratio);
                MCT_transfer.MCT_2b=table(app.LoadedMCTDataListBox.Value{1,5}{1,1}.Ratio);
                MCT_transfer.MCT_2c=table(app.LoadedMCTDataListBox.Value{1,6}{1,1}.Ratio);
                InGaAs_transfer=[];
            elseif MCTSelection<6
                %packaging data code for InSb and InGaAs
                InSb_transfer.InSbWavelength=table(10000./app.LoadedInSbDataListBox.Value{1,1}{1,1}.wavenumber);
                InSb_transfer.InSb_1a=table(app.LoadedInSbDataListBox.Value{1,1}{1,1}.Ratio);
                InSb_transfer.InSb_1b=table(app.LoadedInSbDataListBox.Value{1,2}{1,1}.Ratio);
                InSb_transfer.InSb_1c=table(app.LoadedInSbDataListBox.Value{1,3}{1,1}.Ratio);
                InSb_transfer.InSb_2a=table(app.LoadedInSbDataListBox.Value{1,4}{1,1}.Ratio);
                InSb_transfer.InSb_2b=table(app.LoadedInSbDataListBox.Value{1,5}{1,1}.Ratio);
                InSb_transfer.InSb_2c=table(app.LoadedInSbDataListBox.Value{1,6}{1,1}.Ratio);
                InGaAs_transfer.InGaAsWavelength=table(10000./app.LoadedInGaAsDataListBox.Value{1,1}{1,1}.wavenumber);
                InGaAs_transfer.InGaAs_1a=table(app.LoadedInGaAsDataListBox.Value{1,1}{1,1}.Ratio);
                InGaAs_transfer.InGaAs_1b=table(app.LoadedInGaAsDataListBox.Value{1,2}{1,1}.Ratio);
                InGaAs_transfer.InGaAs_1c=table(app.LoadedInGaAsDataListBox.Value{1,3}{1,1}.Ratio);
                InGaAs_transfer.InGaAs_2a=table(app.LoadedInGaAsDataListBox.Value{1,4}{1,1}.Ratio);
                InGaAs_transfer.InGaAs_2b=table(app.LoadedInGaAsDataListBox.Value{1,5}{1,1}.Ratio);
                InGaAs_transfer.InGaAs_2c=table(app.LoadedInGaAsDataListBox.Value{1,6}{1,1}.Ratio);
                MCT_transfer=[];
            elseif InSbSelection<6
                %packaging data code for MCT and InGaAs
                MCT_transfer.MCTWavelength=table(10000./app.LoadedMCTDataListBox.Value{1,1}{1,1}.wavenumber);
                MCT_transfer.MCT_1a=table(app.LoadedMCTDataListBox.Value{1,1}{1,1}.Ratio);
                MCT_transfer.MCT_1b=table(app.LoadedMCTDataListBox.Value{1,2}{1,1}.Ratio);
                MCT_transfer.MCT_1c=table(app.LoadedMCTDataListBox.Value{1,3}{1,1}.Ratio);
                MCT_transfer.MCT_2a=table(app.LoadedMCTDataListBox.Value{1,4}{1,1}.Ratio);
                MCT_transfer.MCT_2b=table(app.LoadedMCTDataListBox.Value{1,5}{1,1}.Ratio);
                MCT_transfer.MCT_2c=table(app.LoadedMCTDataListBox.Value{1,6}{1,1}.Ratio);
                InGaAs_transfer.InGaAsWavelength=table(10000./app.LoadedInGaAsDataListBox.Value{1,1}{1,1}.wavenumber);
                InGaAs_transfer.InGaAs_1a=table(app.LoadedInGaAsDataListBox.Value{1,1}{1,1}.Ratio);
                InGaAs_transfer.InGaAs_1b=table(app.LoadedInGaAsDataListBox.Value{1,2}{1,1}.Ratio);
                InGaAs_transfer.InGaAs_1c=table(app.LoadedInGaAsDataListBox.Value{1,3}{1,1}.Ratio);
                InGaAs_transfer.InGaAs_2a=table(app.LoadedInGaAsDataListBox.Value{1,4}{1,1}.Ratio);
                InGaAs_transfer.InGaAs_2b=table(app.LoadedInGaAsDataListBox.Value{1,5}{1,1}.Ratio);
                InGaAs_transfer.InGaAs_2c=table(app.LoadedInGaAsDataListBox.Value{1,6}{1,1}.Ratio);
                InSb_transfer=[];
            end 
        elseif sum([InSbSelection,MCTSelection,InGaAsSelection])<12
            uiconfirm(app.LoadOpusUIFigure,'Minimum transferable data files for each detector = 6. Please increase your selection. ','Error!',...
                'Icon','error','Options',{'Okay'},'DefaultOption',1,"CancelOption",1);
            InGaAs_transfer=[]; 
            MCT_transfer=[]; 
            InSb_transfer=[];
            return
        end             
        end
        
        %===========================================================================================================
        %===========================================================================================================
        
        function initialise(app)
            if isempty(app.LoadingStatusTextArea.Value{1,1})
                app.LoadingStatusTextArea.Value='>>>>>>>>>>>>>>>  OPL - OPus Loader';
                app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;'George Green Institute for Electromagnetics Research Group'];
                app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;'Faculty of Engineering - University of Nottingham - UK   '];
                app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;'(c) 2019 Dr Sendy Phang, 2020 Mr David Mabwa             '];
                app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;'==============='];
            end 
        end
        
        %===========================================================================================================
        %===========================================================================================================
        
        function obj=OPL(app,varargin)
            
            if nargin == 0
                % default constructor
            else
                for k=1:nargin-1
                    if strcmp(varargin{k},'filetype')
                        filetype=varargin{k+1};
                    end 
                    if strcmp(varargin{k},'folderDir')
                        folderDir=varargin{k+1};
                    end
                    if strcmp(varargin{k},'filename')
                        filename=varargin{k+1};
                    end
                end
                
                %now populate data depending on the datatype
                if strcmpi(filetype,'OPUS')
                    obj.report=app.CheckOpusFiles(folderDir,filename);
                    [obj.interferogram,obj.phase,obj.data]=app.importOpusFiles(obj.report);
                    obj.origin="OPUS";
                end
                if strcmpi(filetype,'csv')
                    DetailReport=dir(folderDir);
                    obj.data=app.importCsvFiles(DetailReport);
                    obj.Origin="CSV";
                end
            
                
            end
        end
        
        %===========================================================================================================
        %===========================================================================================================
        
        function DetailReport=CheckOpusFiles(app,opusDirectory,opusFileName)
            DetailReport = dir ([opusDirectory '\' opusFileName]);
            % remove useless informations
            DetailReport = rmfield(DetailReport, 'date');
            DetailReport = rmfield(DetailReport, 'bytes');
            DetailReport = rmfield(DetailReport, 'isdir');
            DetailReport = rmfield(DetailReport, 'datenum');
            
            DetailReport(1).MinReqPass = false; % just add a new field 
            [noOpusFiles,~] = size(DetailReport);
            OpusDataName = {'ReferenceInterferogram',...
                'SampleInterferogram',...
                'SampleInterferogramChanged',...
                'ReferenceSpectrum',... 
                'SampleSpectrum',...
                'SamplePhase',...
                'Ratio',...
                'RatioChanged',...
                'RatioAbsorption'};
            
            for k=1:noOpusFiles
                opusAbsFileName = [opusDirectory '\' DetailReport(k).name];
                for d = 1:numel(OpusDataName)
                    DetailReport(k).(OpusDataName{d}) = app.CheckSingleOpusFile(opusAbsFileName,OpusDataName{d});
                end
            end
            
            % check if a file at least satisfy the minimum requirement 
            % i.e. has SampleSpectrum, ReferenceSpectrum and Ratio
            
            for i=1:noOpusFiles
                if ((DetailReport(i).SampleSpectrum && DetailReport(i).ReferenceSpectrum))
                    DetailReport(i).MinReqPass=true;
                elseif ((DetailReport(i).SampleSpectrum && DetailReport(i).Ratio) )
                    DetailReport(i).MinReqPass = true;
                elseif (( DetailReport(i).SampleSpectrum) )
                    DetailReport(i).MinReqPass = true;
                elseif (( DetailReport(i).Ratio) )
                    DetailReport(i).MinReqPass = true;
                else
                    DetailReport(i).MinReqPass = false;
                end
            end
            
            str1=sprintf('>>>>>>> Checking Opus Files');
            str2=sprintf('\tDir: %s \n\tWith name (containing): %s\n',opusDirectory,opusFileName);
            str3=sprintf('\n\tThe script does not check any dimensions, i.e. dimension of wavenumber.\n');
            str4=sprintf('\n\tNumber of Opus files: %d \t Faulty: %d\n',noOpusFiles,noOpusFiles-sum([DetailReport.MinReqPass]));
            app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;str1]; pause(0.25)
            app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;str2]; pause(0.25)
            app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;str3]; pause(0.25)
            app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;str4]; pause(0.25)
            
            if (sum([DetailReport.MinReqPass])<noOpusFiles)
                str5=sprintf('\n\tFAULTY FILES:\n');
                app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;str5]; pause(0.25)
            end
            
            for i=1:noOpusFiles
                if (DetailReport(i).MinReqPass==false)
                    str6=sprintf('\t%s\n',DetailReport(i).name);
                    app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;str6]; pause(0.25)
                end
            end
            
            str7=sprintf('<<<<<<< DONE\n\n');
            app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;str7]; pause(0.25)
            
        end
        
        %===========================================================================================================
        %===========================================================================================================
        
        function isavailable = CheckSingleOpusFile(~,opusAbsFileName,dataset)
            
            try
                [Y, ~,~] = ImportOpus(opusAbsFileName, dataset); % Jake's code is currently not closing the handle if it throws an exception
        %         misc
                fclose('all');
        
                if isempty(Y)
                    isavailable = false;
                else
                    isavailable = true;
                end
            catch ME
                
                fclose('all');
                isavailable = false;
        
            end
        end
        
        %===========================================================================================================
        %===========================================================================================================
        
        function [opusInterferogram,opusPhase,opusData]=importOpusFiles(app,OpusDetailReport)
            
            
            OpusDataName = {'ReferenceInterferogram',...
                'SampleInterferogram',...
                'SampleInterferogramChanged',...
                'ReferenceSpectrum',... 
                'SampleSpectrum',...
                'SamplePhase',...
                'Ratio',...
                'RatioChanged',...
                'RatioAbsorption'};
            
            [noTotOpusFiles,~] = size(OpusDetailReport);
            noUseableOpusFiles = sum([OpusDetailReport.MinReqPass]);
            
            str1=sprintf('>>>>>>> Importing %d Opus Files \n',noUseableOpusFiles);
            app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;str1]; pause(0.25)
            
            %iterator=noUseableOpusFiles;
            for i=noTotOpusFiles:-1:1
                if (OpusDetailReport(i).MinReqPass)
                    opusData(i).name=OpusDetailReport(i).name;
                    opusInterferogram(i).name=OpusDetailReport(i).name;
                    opusPhase(i).name=OpusDetailReport(i).name;
                    
                    %if in linux use / not\ for directory
                    if ismac %code to run on mac platform
                    elseif isunix
                        opusAbsFileName=[OpusDetailReport(i).folder '/' OpusDetailReport(i).name];
                    elseif ispc %code to run on windows platform
                        opusAbsFileName = [OpusDetailReport(i).folder '\' OpusDetailReport(i).name];
                    end
                    
                    for d=1:numel(OpusDataName)
                        if(OpusDetailReport(i).(OpusDataName{d}))
                            if strcmpi(OpusDataName{d},'ReferenceInterferogram') || strcmpi(OpusDataName{d},'SampleInterferogram')
                                [Y,X,~]=app.ImportOpusWrapper(opusAbsFileName,(OpusDataName{d}));
                                opusInterferogram(i).InterferogramIndices = X;
                                opusInterferogram(i).(OpusDataName{d}) = Y;
                            elseif strcmpi(OpusDataName{d},'SamplePhase')
                                [Y,X,~]=app.ImportOpusWrapper(opusAbsFileName,(OpusDataName{d}) ) ;
                                opusPhase(i).PhaseIndices = X;   
                                opusPhase(i).(OpusDataName{d}) = Y;
                            else
                                [Y,X,~]=app.ImportOpusWrapper(opusAbsFileName,(OpusDataName{d}) ) ;
                                opusData(i).wavenumber = X;   
                                opusData(i).(OpusDataName{d}) = Y;
                            end
                        end
                    end
                end
            end
            str2=sprintf('<<<<<<< DONE\n\n');
            app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;str2]; pause(0.25)
        end
        
        %===========================================================================================================
        %===========================================================================================================
        
        function [Y,X,MiscProp]=ImportOpusWrapper(~,opusAbsFileName,dataset)
            try
                [Y,X,MiscProp] = ImportOpus(opusAbsFileName, dataset); 
        
                Y = single(Y);
                X = single(X)'; % make the wave number Nx1 array
                 
                fclose('all');
            catch ME
                fclose('all');
            end
        end
        
        %===========================================================================================================
        %===========================================================================================================
        
        function csvData=importCsvFiles(app,csvDetailReport)
            if ismac
                %code to run on mac platform
            elseif isunix
                csvAbsFileName = [csvDetailReport(3).folder '/' csvDetailReport(3).name];
            elseif ispc % Code to run on Windows platform
                csvAbsFileName = [csvDetailReport(3).folder '\' csvDetailReport(3).name];
            end
            
            temporary_reading=readtable(csvAbsFileName);
            wavenumber=table2array(temporary_reading(1,1:end-1));
            nameList=table2cell(temporary_reading(2:end,end));
            
            num_dataset=length(nameList);
            
            for edata=num_dataset:-1:1
                csvData(edata,1).name = nameList(edata);
                csvData(edata,1).wavenumber = wavenumber;
                csvData(edata,1).(csvDetailReport(3).name(1:end-4)) = table2array(temporary_reading(edata+1,1:end-1));
            end
            app.LoadingStatusTextArea.Value=[app.LoadingStatusTextArea.Value;'<<<< CSV Data Imported Successfully! >>>>']; pause(0.25)
        end
        
        %===========================================================================================================
        %===========================================================================================================
         
    end 


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, InSb_transfer, MCT_transfer, InGaAs_Transfer)
            app.CallingApp = mainapp;
        end

        % Menu selected function: LoadMenu_2
        function LoadMenu_2Selected(app, event)
            app.LoadOPUSData
        end

        % Button pushed function: TransferButton
        function TransferButtonPushed(app, event)
            [InSb_transfer,MCT_transfer,InGaAs_transfer]=app.PackageData;
            if isempty(InGaAs_transfer) && ~isempty(MCT_transfer) && ~isempty(InSb_transfer)   
                % Call main app's public function
                TransferData(app.CallingApp,InSb_transfer,MCT_transfer,["InSb","MCT"])
            elseif isempty(MCT_transfer) && ~isempty(InGaAs_transfer) && ~isempty(InSb_transfer) 
                TransferData(app.CallingApp,InSb_transfer,InGaAs_transfer,["InSb","InGaAs"])
            elseif isempty(InSb_transfer) && ~isempty(InGaAs_transfer) && ~isempty(MCT_transfer)     
                TransferData(app.CallingApp,MCT_transfer,InGaAs_transfer,["MCT","InGaAs"])
            elseif isempty(InGaAs_transfer) && isempty(MCT_transfer) && isempty(InSb_transfer) 
                return
            end
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: LoadOpusUIFigure
        function LoadOpusUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadOpusUIFigure = uifigure('Visible', 'off');
            app.LoadOpusUIFigure.Name = 'Load Opus';
            app.LoadOpusUIFigure.Icon = 'atten icon.png';
            app.LoadOpusUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadOpusUIFigureCloseRequest, true);
            app.LoadMenu_2 = uimenu(app.LoadOpusUIFigure);
            app.LoadMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LoadMenu_2Selected, true);
            app.LoadMenu_2.Text = 'Load';
            app.LoadDataLabel = uilabel(app.LoadOpusUIFigure);
            app.LoadDataLabel.Text = 'Load Data';
            app.LoadedInSbDataListBoxLabel = uilabel(app.LoadOpusUIFigure);
            app.LoadedInSbDataListBoxLabel.Text = 'Loaded InSb Data';
            app.LoadedInSbDataListBox = uilistbox(app.LoadOpusUIFigure);
            app.LoadedInSbDataListBox.Items = {};
            app.LoadedInSbDataListBox.Multiselect = 'on';
            app.LoadedInSbDataListBox.Value = {};
            app.InSbLamp = uilamp(app.LoadOpusUIFigure);
            app.LoadedMCTDataListBoxLabel = uilabel(app.LoadOpusUIFigure);
            app.LoadedMCTDataListBoxLabel.Text = 'Loaded MCT Data';
            app.LoadedMCTDataListBox = uilistbox(app.LoadOpusUIFigure);
            app.LoadedMCTDataListBox.Items = {};
            app.LoadedMCTDataListBox.Multiselect = 'on';
            app.LoadedMCTDataListBox.Value = {};
            app.MCTLamp = uilamp(app.LoadOpusUIFigure);
            app.LoadedInGaAsDataListBoxLabel = uilabel(app.LoadOpusUIFigure);
            app.LoadedInGaAsDataListBoxLabel.Text = 'Loaded InGaAs Data';
            app.LoadedInGaAsDataListBox = uilistbox(app.LoadOpusUIFigure);
            app.LoadedInGaAsDataListBox.Items = {};
            app.LoadedInGaAsDataListBox.Multiselect = 'on';
            app.LoadedInGaAsDataListBox.Value = {};
            app.Label = uilabel(app.LoadOpusUIFigure);
            app.Label.Text = '';
            app.InGaAsLamp = uilamp(app.LoadOpusUIFigure);
            app.TransferButton = uibutton(app.LoadOpusUIFigure, 'push');
            app.TransferButton.ButtonPushedFcn = createCallbackFcn(app, @TransferButtonPushed, true);
            app.TransferButton.Text = 'Transfer';
            app.LoadingStatusTextAreaLabel = uilabel(app.LoadOpusUIFigure);
            app.LoadingStatusTextAreaLabel.Text = 'Loading Status';
            app.LoadingStatusTextArea = uitextarea(app.LoadOpusUIFigure);
            app.LoadingStatusTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.LoadOpusUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadOpusApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadOpusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadOpusUIFigure)
        end
    end
end